classdef AdjustImageWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdjustImageUIFigure            matlab.ui.Figure
        MaximumInPixelIntensitySlider  matlab.ui.control.Slider
        MaximumInPixelIntensitySliderLabel  matlab.ui.control.Label
        BrightnessSlider               matlab.ui.control.Slider
        BrightnessSliderLabel          matlab.ui.control.Label
        BrightnessandContrastPanel     matlab.ui.container.Panel
        MaxContrastLamp                matlab.ui.control.Lamp
        MaximizeContrastButton         matlab.ui.control.Button
        MinimumInPixelIntensitySlider  matlab.ui.control.Slider
        MinimumInPixelIntensitySliderLabel  matlab.ui.control.Label
        in_out_axis                    matlab.ui.control.UIAxes
        adjusted_image_hist_working    matlab.ui.control.UIAxes
        adjusted_image_hist_before_inset  matlab.ui.control.UIAxes
        adjusted_image_hist_before     matlab.ui.control.UIAxes
        AdjustedImagePanel             matlab.ui.container.Panel
        LoadAdjustedImageButton        matlab.ui.control.Button
        RevertChangesButton            matlab.ui.control.Button
        ReverttoOriginalImageButton    matlab.ui.control.Button
        adjusted_image_hist_after      matlab.ui.control.UIAxes
        adjusted_image_axis            matlab.ui.control.UIAxes
        OriginalImagePanel             matlab.ui.container.Panel
        CropImageButton                matlab.ui.control.Button
        original_image_hist_inset      matlab.ui.control.UIAxes
        original_image_hist            matlab.ui.control.UIAxes
        original_image_axis            matlab.ui.control.UIAxes
        adjusted_image_hist_working_inset  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        GelBoxApp % Description
        n
    end

    properties (Access = public)
        original_image
        im_crop_box
        adjusted_image = []
        adjusted_image_2 = []
        normalized_original_image % Description
        cropped_image % Description
        crop_pos % Description
        bit_d_val % Description
        max_contrast = false
        in_out % Description
    end

    methods (Access = public)

        function UpdateAdjustedImageDisplay(app,image)

            cla(app.adjusted_image_axis);
            cla(app.adjusted_image_hist_working);
            cla(app.adjusted_image_hist_working_inset);
            
            center_image_with_preserved_aspect_ratio( ...
                image, ...
                app.adjusted_image_axis,[0 1]);
            colorbar(app.adjusted_image_axis)

            [counts, edges] = histcounts(image,100);
            bar(app.adjusted_image_hist_working,edges(1:(end-1)), counts);
            xlim(app.adjusted_image_hist_working,[-0.01 1.01])
            bar(app.adjusted_image_hist_after,edges(1:(end-1)), counts);
            xlim(app.adjusted_image_hist_after,[-0.01 1.01])
            bar(app.adjusted_image_hist_working_inset,edges(1:(end-1)), counts);
            xlim(app.adjusted_image_hist_working_inset,[-0.01 0.5])
            ylim(app.adjusted_image_hist_working_inset, [0 max(counts(1:50))])

        end

        function LoadImageAdjustments(app)

            app.original_image = app.GelBoxApp.gel_data.original_image;

            bit_d = class(app.original_image);

            switch bit_d
                case 'uint8'
                    app.bit_d_val = 2^8;
                    app.original_image = double(app.original_image)./(app.bit_d_val);
                case 'uint16'
                    app.bit_d_val = 2^16;
                    app.original_image = double(app.original_image)./(app.bit_d_val);
            end


            [counts, edges] = histcounts(app.original_image,100);
            bar(app.original_image_hist,edges(1:(end-1)), counts);
            center_image_with_preserved_aspect_ratio( ...
                app.original_image, ...
                app.original_image_axis,[]);
            colorbar(app.original_image_axis)

            app.adjusted_image = app.GelBoxApp.gel_data.adjusted_image;
            bit_d = class(app.adjusted_image);

            switch bit_d
                case 'uint8'
                    app.bit_d_val = 2^8;
                    app.adjusted_image = double(app.adjusted_image)./(app.bit_d_val);
                case 'uint16'
                    app.bit_d_val = 2^16;
                    app.adjusted_image = double(app.adjusted_image)./(app.bit_d_val);
            end

            UpdateAdjustedImageDisplay(app,app.adjusted_image)

            app.BrightnessSlider.Value = app.GelBoxApp.gel_data.image_adjustments.brightness;
            app.MinimumInPixelIntensitySlider.Value = app.GelBoxApp.gel_data.image_adjustments.contrast_lower;
            app.MaximumInPixelIntensitySlider.Value = app.GelBoxApp.gel_data.image_adjustments.contrast_upper;

            if app.GelBoxApp.gel_data.image_adjustments.max_contrast
                app.MaxContrastLamp.Enable = 'on';
            end


            app.im_crop_box = images.roi.Rectangle(app.original_image_axis, ...
                'Position',app.GelBoxApp.gel_data.image_adjustments.crop_pos);

            app.im_crop_box.FaceAlpha = 0;
            app.crop_pos = app.im_crop_box.Position;

            addlistener(app.im_crop_box,"MovingROI", ...
                @(src,evt) crop_box_position_2(evt));

            app.RevertChangesButton.Enable = 'off';

            function crop_box_position_2(evt);
                app.cropped_image = [];
                app.crop_pos = app.im_crop_box.Position;
                app.cropped_image = imcrop(app.original_image,app.crop_pos);
                app.MaxContrastLamp.Enable = "off";
                UpdateAdjustedImageDisplay(app,app.cropped_image);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            movegui(app.AdjustImageUIFigure,'center')
            colormap(app.AdjustImageUIFigure, 'gray');
            xlim(app.adjusted_image_hist_working,[0 1]);
            xlim(app.adjusted_image_hist_working,[0 1]);
            cla(app.original_image_hist);
            app.GelBoxApp = caller;
            app.original_image_hist.YAxis.Exponent = 0;
            app.original_image_hist_inset.YAxis.Exponent = 0;
            app.adjusted_image_hist_working.YAxis.Exponent = 0;
            app.adjusted_image_hist_working_inset.YAxis.Exponent = 0;
            app.adjusted_image_hist_before.YAxis.Exponent = 0;
            app.adjusted_image_hist_after.YAxis.Exponent = 0;

            
%             app.hist_in.YAxis.Exponent = 0;
%             app.hist_out.XAxis.Exponent = 0;

            if isfield(app.GelBoxApp.gel_data,'image_adjustments')
                LoadImageAdjustments(app)
            else
                app.original_image = caller.gel_data.im_data;
                
                bit_d = class(app.original_image);

                switch bit_d
                    case 'uint8'
                        app.bit_d_val = 2^8;
                        app.original_image = double(app.original_image)./(app.bit_d_val);
                    case 'uint16'
                        app.bit_d_val = 2^16;
                        app.original_image = double(app.original_image)./(app.bit_d_val);
                end

%                 imshow(app.original_image,'Parent',app.original_image_axis)
                center_image_with_preserved_aspect_ratio( ...
                    app.original_image, ...
                    app.original_image_axis,[]);

                [counts, edges] = histcounts(app.original_image,100);
                bar(app.original_image_hist,edges(1:(end-1)), counts);
                bar(app.original_image_hist_inset,edges(1:(end-1)), counts);
                
                col_bar = colorbar(app.original_image_axis);
                ax_lim(1) = col_bar.Limits(1) - 0.0001;
                ax_lim(2) = col_bar.Limits(2) + 0.0001;
                xlim(app.original_image_hist,[ax_lim(1) ax_lim(2)])
                xlim(app.original_image_hist_inset,[ax_lim(1) 0.5*(ax_lim(1)+ax_lim(2))])
                app.in_out = linspace(0,1);
                plot(app.in_out_axis,app.in_out,app.in_out,'LineWidth',2,'Color','k')
%                 bar(app.hist_in,edges(1:(end-1)), counts);
%                 barh(app.hist_out,edges(1:(end-1)), counts);

                UpdateAdjustedImageDisplay(app,app.original_image)
                bar(app.adjusted_image_hist_before,edges(1:(end-1)), counts);
                bar(app.adjusted_image_hist_before_inset,edges(1:(end-1)), counts);
                xlim(app.adjusted_image_hist_before,[-0.01 1.01])

            end

        end

        % Button pushed function: CropImageButton
        function CropImageButtonPushed(app, event)
            app.im_crop_box = drawrectangle(app.original_image_axis);
            app.im_crop_box.FaceAlpha = 0;
            app.crop_pos = app.im_crop_box.Position;

            app.cropped_image = imcrop(app.original_image,app.crop_pos);

            addlistener(app.im_crop_box,"MovingROI", ...
                @(src,evt) crop_box_position(evt));

            UpdateAdjustedImageDisplay(app,app.cropped_image)
            

            cla(app.adjusted_image_hist_before);

            [counts, edges] = histcounts(app.cropped_image,100);
            bar(app.adjusted_image_hist_before,edges(1:(end-1)), counts);
            xlim(app.adjusted_image_hist_before,[-0.01 1.01])
            
            function crop_box_position(evt);
                app.cropped_image = [];
                app.crop_pos = app.im_crop_box.Position;
                app.cropped_image = imcrop(app.original_image,app.crop_pos);
                app.MaxContrastLamp.Enable = "off";
                UpdateAdjustedImageDisplay(app,app.cropped_image);
                [counts, edges] = histcounts(app.cropped_image,100);
                bar(app.adjusted_image_hist_before_inset,edges(1:(end-1)), counts);

            end

        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.MinimumInPixelIntensitySlider.Value == 0 && app.MaximumInPixelIntensitySlider.Value == 1
                if changingValue < 0
                    if isempty(app.cropped_image)
                        app.cropped_image = app.original_image;
                    end
                    app.adjusted_image = imadjust(app.cropped_image,[0 1], [0 1+changingValue]);
                    app.in_out = linspace(0,1);
                    app.in_out = imadjust(app.in_out,[0 1], [0 1+changingValue]);
                    cla(app.in_out_axis)
                    plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                    xlim(app.in_out_axis,[0 1])
                    ylim(app.in_out_axis,[0 1])
                    app.MinimumInPixelIntensitySlider.Value = 0;
                    app.MaximumInPixelIntensitySlider.Value = 1+changingValue;
                    UpdateAdjustedImageDisplay(app,app.adjusted_image);
                else
                    if isempty(app.cropped_image)
                        app.cropped_image = app.original_image;
                    end
                    app.adjusted_image = imadjust(app.cropped_image,[0 1], [changingValue 1]);
                    app.in_out = linspace(0,1);
                    app.in_out = imadjust(app.in_out,[0 1], [changingValue 1]);
                    cla(app.in_out_axis)
                    plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                    xlim(app.in_out_axis,[0 1])
                    ylim(app.in_out_axis,[0 1])
                    app.MinimumInPixelIntensitySlider.Value = changingValue;
                    app.MaximumInPixelIntensitySlider.Value = 1;
                    UpdateAdjustedImageDisplay(app,app.adjusted_image);
                end
            else
                if changingValue < 0
                    app.adjusted_image_2 = imadjust(app.adjusted_image,[0 1], [0 1+changingValue]);
                    app.in_out = linspace(0,1);
                    app.in_out = imadjust(app.in_out,[0 1], [0 1+changingValue]);
                    cla(app.in_out_axis)
                    plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                    xlim(app.in_out_axis,[0 1])
                    ylim(app.in_out_axis,[0 1])
                    app.MinimumInPixelIntensitySlider.Value = 0;
                    app.MaximumInPixelIntensitySlider.Value = 1+changingValue;
                    UpdateAdjustedImageDisplay(app,app.adjusted_image_2);
                else
                    app.adjusted_image_2 = imadjust(app.adjusted_image,[0 1], [changingValue 1]);
                    app.in_out = linspace(0,1);
                    app.in_out = imadjust(app.in_out,[0 1], [changingValue 1]);
                    cla(app.in_out_axis)
                    plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                    xlim(app.in_out_axis,[0 1])
                    ylim(app.in_out_axis,[0 1])
                    app.MinimumInPixelIntensitySlider.Value = changingValue;
                    app.MaximumInPixelIntensitySlider.Value = 1;
                    UpdateAdjustedImageDisplay(app,app.adjusted_image_2);
                end
            end
        end

        % Button pushed function: MaximizeContrastButton
        function MaximizeContrastButtonPushed(app, event)
            if isempty(app.adjusted_image)
                if isempty(app.cropped_image)
                    app.cropped_image = app.original_image;
                end
%                 app.adjusted_image = imadjust(app.cropped_image);
                app.adjusted_image = imadjust(app.cropped_image,stretchlim(app.cropped_image,[0 1]),[0 1]);
                app.in_out = imadjust(app.in_out,stretchlim(app.in_out,[0 1]),[0 1]);
                cla(app.in_out_axis)
                plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                xlim(app.in_out_axis,[0 1])
                ylim(app.in_out_axis,[0 1])
                UpdateAdjustedImageDisplay(app,app.adjusted_image);
            else
%                 app.adjusted_image_2 = imadjust(app.adjusted_image);
                app.adjusted_image_2 = imadjust(app.adjusted_image,stretchlim(app.adjusted_image,[0 1]),[0 1]);
                app.in_out = imadjust(app.in_out,stretchlim(app.in_out,[0 1]),[0 1]);
                cla(app.in_out_axis)
                plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                xlim(app.in_out_axis,[0 1])
                ylim(app.in_out_axis,[0 1])
                UpdateAdjustedImageDisplay(app,app.adjusted_image_2);
            end

            app.max_contrast = true;
            app.MaxContrastLamp.Enable = 'on';

        end

        % Value changing function: MinimumInPixelIntensitySlider
        function MinimumInPixelIntensitySliderValueChanging(app, event)
            changingValue = event.Value;
            if app.BrightnessSlider.Value == 0
                app.adjusted_image = imadjust(app.cropped_image,[changingValue app.MaximumInPixelIntensitySlider.Value],[]);
                app.in_out = linspace(0,1);
                app.in_out = imadjust(app.in_out,[changingValue app.MaximumInPixelIntensitySlider.Value],[]);
                cla(app.in_out_axis)
                plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                xlim(app.in_out_axis,[0 1])
                ylim(app.in_out_axis,[0 1])
                UpdateAdjustedImageDisplay(app,app.adjusted_image);
            else
                app.adjusted_image_2 = imadjust(app.adjusted_image,[changingValue app.MaximumInPixelIntensitySlider.Value],[]);
                app.in_out = linspace(0,1);
                app.in_out = imadjust(app.in_out,[changingValue app.MaximumInPixelIntensitySlider.Value],[]);
                cla(app.in_out_axis)
                plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                xlim(app.in_out_axis,[0 1])
                ylim(app.in_out_axis,[0 1])
                UpdateAdjustedImageDisplay(app,app.adjusted_image_2);
            end
        end

        % Value changing function: MaximumInPixelIntensitySlider
        function MaximumInPixelIntensitySliderValueChanging(app, event)
            changingValue = event.Value;
            if app.BrightnessSlider.Value == 0
                app.adjusted_image = imadjust(app.cropped_image,[app.MinimumInPixelIntensitySlider.Value changingValue],[]);
                app.in_out = linspace(0,1);
                app.in_out = imadjust(app.in_out,[app.MinimumInPixelIntensitySlider.Value changingValue],[]);
                cla(app.in_out_axis)
                plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                xlim(app.in_out_axis,[0 1])
                ylim(app.in_out_axis,[0 1])
                UpdateAdjustedImageDisplay(app,app.adjusted_image);
            else
                app.adjusted_image_2 = imadjust(app.adjusted_image,[app.MinimumInPixelIntensitySlider.Value changingValue],[]);
                app.in_out = linspace(0,1);
                app.in_out = imadjust(app.in_out,[app.MinimumInPixelIntensitySlider.Value changingValue],[]);
                cla(app.in_out_axis)
                plot(app.in_out_axis,linspace(0,1),app.in_out,'LineWidth',2,'Color','k')
                xlim(app.in_out_axis,[0 1])
                ylim(app.in_out_axis,[0 1])
                UpdateAdjustedImageDisplay(app,app.adjusted_image_2);
            end
        end

        % Button pushed function: RevertChangesButton
        function RevertChangesButtonPushed(app, event)
            app.adjusted_image = [];
            app.adjusted_image_2 = [];
            app.MinimumInPixelIntensitySlider.Value = 0;
            app.MaximumInPixelIntensitySlider.Value = 1;
            app.BrightnessSlider.Value = 0;
            app.in_out = linspace(0,1);
            plot(app.in_out_axis,app.in_out,app.in_out,'LineWidth',2,'Color','k')
            UpdateAdjustedImageDisplay(app,app.cropped_image)
            app.MaxContrastLamp.Enable = 'off';
        end

        % Button pushed function: LoadAdjustedImageButton
        function LoadAdjustedImageButtonPushed(app, event)

            app.GelBoxApp.gel_data.image_adjustments.crop_pos = app.crop_pos;
            app.GelBoxApp.gel_data.image_adjustments.brightness = app.BrightnessSlider.Value;
            app.GelBoxApp.gel_data.image_adjustments.contrast_lower = app.MinimumInPixelIntensitySlider.Value;
            app.GelBoxApp.gel_data.image_adjustments.contrast_upper = app.MaximumInPixelIntensitySlider.Value;
            app.GelBoxApp.gel_data.image_adjustments.max_contrast = app.max_contrast;

            if isempty(app.cropped_image)
                app.GelBoxApp.gel_data.adjusted_image = [];
            elseif isempty(app.adjusted_image)
                app.GelBoxApp.gel_data.adjusted_image = app.cropped_image.*app.bit_d_val;
            elseif isempty(app.adjusted_image_2)
                app.GelBoxApp.gel_data.adjusted_image = app.adjusted_image.*app.bit_d_val;
            else
                app.GelBoxApp.gel_data.adjusted_image = app.adjusted_image_2.*app.bit_d_val;
            end

            switch app.bit_d_val
                case 2^8
                    app.GelBoxApp.gel_data.adjusted_image = uint8(app.GelBoxApp.gel_data.adjusted_image);
                case 2^16
                    app.GelBoxApp.gel_data.adjusted_image = uint16(app.GelBoxApp.gel_data.adjusted_image);
            end
            ImageAdjusted(app.GelBoxApp)
            delete(app)
        end

        % Button pushed function: ReverttoOriginalImageButton
        function ReverttoOriginalImageButtonPushed(app, event)
            app.adjusted_image = [];
            app.adjusted_image_2 = [];
            app.MinimumInPixelIntensitySlider.Value = 0;
            app.MaximumInPixelIntensitySlider.Value = 1;
            app.BrightnessSlider.Value = 0;
            app.in_out = linspace(0,1);
            plot(app.in_out_axis,app.in_out,app.in_out,'LineWidth',2,'Color','k')
            UpdateAdjustedImageDisplay(app,app.original_image)
            app.MaxContrastLamp.Enable = 'off';
            app.RevertChangesButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdjustImageUIFigure = uifigure('Visible', 'off');
            app.AdjustImageUIFigure.Name = 'Adjust Image';
            app.adjusted_image_hist_working_inset = uiaxes(app.AdjustImageUIFigure);
            xlabel(app.adjusted_image_hist_working_inset, 'Pixel Intensity')
            ylabel(app.adjusted_image_hist_working_inset, 'Num. of Pxs')
            app.OriginalImagePanel = uipanel(app.AdjustImageUIFigure);
            app.OriginalImagePanel.Title = 'Original Image';
            app.original_image_axis = uiaxes(app.OriginalImagePanel);
            app.original_image_axis.XTick = [];
            app.original_image_axis.YTick = [];
            app.original_image_hist = uiaxes(app.OriginalImagePanel);
            title(app.original_image_hist, 'Original Image Histogram')
            xlabel(app.original_image_hist, 'Pixel Intensity')
            ylabel(app.original_image_hist, 'Number of Pixels')
            app.original_image_hist_inset = uiaxes(app.OriginalImagePanel);
            xlabel(app.original_image_hist_inset, 'Pixel Intensity')
            ylabel(app.original_image_hist_inset, 'Num. of Pxs')
            app.CropImageButton = uibutton(app.OriginalImagePanel, 'push');
            app.CropImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropImageButtonPushed, true);
            app.CropImageButton.Text = 'Crop Image';
            app.AdjustedImagePanel = uipanel(app.AdjustImageUIFigure);
            app.AdjustedImagePanel.Title = 'Adjusted Image';
            app.adjusted_image_axis = uiaxes(app.AdjustedImagePanel);
            app.adjusted_image_axis.XTick = [];
            app.adjusted_image_axis.YTick = [];
            app.adjusted_image_hist_after = uiaxes(app.AdjustedImagePanel);
            title(app.adjusted_image_hist_after, 'Adjusted Image Histogram: Histogram After Modifications')
            xlabel(app.adjusted_image_hist_after, 'Pixel Intensity')
            ylabel(app.adjusted_image_hist_after, 'Number of Pixels')
            app.ReverttoOriginalImageButton = uibutton(app.AdjustedImagePanel, 'push');
            app.ReverttoOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReverttoOriginalImageButtonPushed, true);
            app.ReverttoOriginalImageButton.Text = 'Revert to Original Image';
            app.RevertChangesButton = uibutton(app.AdjustedImagePanel, 'push');
            app.RevertChangesButton.ButtonPushedFcn = createCallbackFcn(app, @RevertChangesButtonPushed, true);
            app.RevertChangesButton.Text = 'Revert Changes';
            app.LoadAdjustedImageButton = uibutton(app.AdjustedImagePanel, 'push');
            app.LoadAdjustedImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAdjustedImageButtonPushed, true);
            app.LoadAdjustedImageButton.Text = 'Load Adjusted Image';
            app.BrightnessandContrastPanel = uipanel(app.AdjustImageUIFigure);
            app.BrightnessandContrastPanel.Title = 'Brightness and Contrast';
            app.adjusted_image_hist_before = uiaxes(app.BrightnessandContrastPanel);
            title(app.adjusted_image_hist_before, 'Adjusted Image: Histogram Before Modifications')
            xlabel(app.adjusted_image_hist_before, 'Pixel Intensity')
            ylabel(app.adjusted_image_hist_before, 'Number of Pixels')
            app.adjusted_image_hist_before_inset = uiaxes(app.BrightnessandContrastPanel);
            xlabel(app.adjusted_image_hist_before_inset, 'Pixel Intensity')
            ylabel(app.adjusted_image_hist_before_inset, 'Num. of Pxs')
            app.adjusted_image_hist_working = uiaxes(app.BrightnessandContrastPanel);
            title(app.adjusted_image_hist_working, 'Adjusted Image: Working Histogram')
            xlabel(app.adjusted_image_hist_working, 'Pixel Intensity')
            ylabel(app.adjusted_image_hist_working, 'Number of Pixels')
            app.in_out_axis = uiaxes(app.BrightnessandContrastPanel);
            xlabel(app.in_out_axis, 'In (Pixel Intensity)')
            ylabel(app.in_out_axis, 'Out (Pixel Intensity)')
            zlabel(app.in_out_axis, 'Z')
            app.MinimumInPixelIntensitySliderLabel = uilabel(app.BrightnessandContrastPanel);
            app.MinimumInPixelIntensitySliderLabel.Text = 'Minimum In (Pixel Intensity)';
            app.MinimumInPixelIntensitySlider = uislider(app.BrightnessandContrastPanel);
            app.MinimumInPixelIntensitySlider.Limits = [0 1];
            app.MinimumInPixelIntensitySlider.ValueChangingFcn = createCallbackFcn(app, @MinimumInPixelIntensitySliderValueChanging, true);
            app.MaximizeContrastButton = uibutton(app.BrightnessandContrastPanel, 'push');
            app.MaximizeContrastButton.ButtonPushedFcn = createCallbackFcn(app, @MaximizeContrastButtonPushed, true);
            app.MaximizeContrastButton.Text = 'Maximize Contrast';
            app.MaxContrastLamp = uilamp(app.BrightnessandContrastPanel);
            app.MaxContrastLamp.Enable = 'off';
            app.MaxContrastLamp.Visible = 'off';
            app.BrightnessSliderLabel = uilabel(app.AdjustImageUIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.AdjustImageUIFigure);
            app.BrightnessSlider.Limits = [-1 1];
            app.BrightnessSlider.MajorTicks = [-1 -0.6 -0.2 0 0.2 0.6 1];
            app.BrightnessSlider.MajorTickLabels = {'Darken', '', '', '', '', '', 'Brighten'};
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.MaximumInPixelIntensitySliderLabel = uilabel(app.AdjustImageUIFigure);
            app.MaximumInPixelIntensitySliderLabel.Text = 'Maximum In (Pixel Intensity)';
            app.MaximumInPixelIntensitySlider = uislider(app.AdjustImageUIFigure);
            app.MaximumInPixelIntensitySlider.Limits = [0 1];
            app.MaximumInPixelIntensitySlider.ValueChangingFcn = createCallbackFcn(app, @MaximumInPixelIntensitySliderValueChanging, true);
            app.MaximumInPixelIntensitySlider.Value = 1;
            % Show the figure after all components are created
            app.AdjustImageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustImageWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdjustImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdjustImageUIFigure)
        end
    end
end