classdef FittingOptionsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingOptionsUIFigure          matlab.ui.Figure
        FittingParametersBand3Panel     matlab.ui.container.Panel
        FinalValueLabel_3               matlab.ui.control.Label
        EstimatedStartingValueLabel_3   matlab.ui.control.Label
        SkewnessStartingValue_Band3     matlab.ui.control.EditField
        ShapeParameterLabel_12          matlab.ui.control.Label
        AmplitudeStartingValue_Band3    matlab.ui.control.EditField
        ShapeParameterLabel_11          matlab.ui.control.Label
        ShapeParameterStartingValue_Band3  matlab.ui.control.EditField
        ShapeParameterLabel_10          matlab.ui.control.Label
        PeakLocationStartingValue_Band3  matlab.ui.control.EditField
        PeakLocationEditFieldLabel_4    matlab.ui.control.Label
        SkewnessFinalValue_Band3        matlab.ui.control.EditField
        AmplitudeFinalValue_Band3       matlab.ui.control.EditField
        ShapeParameterFinalValue_Band3  matlab.ui.control.EditField
        PeakLocationFinalValue_Band3    matlab.ui.control.EditField
        FittingParametersBand2Panel     matlab.ui.container.Panel
        FinalValueLabel_2               matlab.ui.control.Label
        EstimatedStartingValueLabel_2   matlab.ui.control.Label
        SkewnessStartingValue_Band2     matlab.ui.control.EditField
        ShapeParameterLabel_9           matlab.ui.control.Label
        AmplitudeStartingValue_Band2    matlab.ui.control.EditField
        ShapeParameterLabel_8           matlab.ui.control.Label
        ShapeParameterStartingValue_Band2  matlab.ui.control.EditField
        ShapeParameterLabel_7           matlab.ui.control.Label
        PeakLocationStartingValue_Band2  matlab.ui.control.EditField
        PeakLocationEditFieldLabel_3    matlab.ui.control.Label
        SkewnessFinalValue_Band2        matlab.ui.control.EditField
        AmplitudeFinalValue_Band2       matlab.ui.control.EditField
        ShapeParameterFinalValue_Band2  matlab.ui.control.EditField
        PeakLocationFinalValue_Band2    matlab.ui.control.EditField
        FittingParametersBand1Panel     matlab.ui.container.Panel
        SkewnessStartingValue_Band1     matlab.ui.control.EditField
        ShapeParameterLabel_3           matlab.ui.control.Label
        AmplitudeStartingValue_Band1    matlab.ui.control.EditField
        ShapeParameterLabel_2           matlab.ui.control.Label
        ShapeParameterStartingValue_Band1  matlab.ui.control.EditField
        ShapeParameterLabel             matlab.ui.control.Label
        PeakLocationStartingValue_Band1  matlab.ui.control.EditField
        PeakLocationEditFieldLabel      matlab.ui.control.Label
        SkewnessFinalValue_Band1        matlab.ui.control.EditField
        AmplitudeFinalValue_Band1       matlab.ui.control.EditField
        ShapeParameterFinalValue_Band1  matlab.ui.control.EditField
        PeakLocationFinalValue_Band1    matlab.ui.control.EditField
        FinalValueLabel                 matlab.ui.control.Label
        EstimatedStartingValueLabel     matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        SharedParametersLabel           matlab.ui.control.Label
        SkewnessparameterCheckBox       matlab.ui.control.CheckBox
        ShapeparameterCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        GelBoxApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.GelBoxApp = caller;
            app.ShapeparameterCheckBox.Value = app.GelBoxApp.fitting_options.shared_shape;
            app.SkewnessparameterCheckBox.Value = app.GelBoxApp.fitting_options.shared_skewness;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            shape = app.ShapeparameterCheckBox.Value;
            skewness = app.SkewnessparameterCheckBox.Value;
            UpdateFittingOptions(app.GelBoxApp,shape,skewness);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingOptionsUIFigure = uifigure('Visible', 'off');
            app.FittingOptionsUIFigure.Name = 'Fitting Options';
            app.ShapeparameterCheckBox = uicheckbox(app.FittingOptionsUIFigure);
            app.ShapeparameterCheckBox.Text = 'Shape parameter';
            app.ShapeparameterCheckBox.Value = true;
            app.SkewnessparameterCheckBox = uicheckbox(app.FittingOptionsUIFigure);
            app.SkewnessparameterCheckBox.Text = 'Skewness parameter';
            app.SkewnessparameterCheckBox.Value = true;
            app.SharedParametersLabel = uilabel(app.FittingOptionsUIFigure);
            app.SharedParametersLabel.Text = 'Shared Parameters';
            app.UpdateButton = uibutton(app.FittingOptionsUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.FittingParametersBand1Panel = uipanel(app.FittingOptionsUIFigure);
            app.FittingParametersBand1Panel.Title = 'Fitting Parameters: Band 1';
            app.EstimatedStartingValueLabel = uilabel(app.FittingParametersBand1Panel);
            app.EstimatedStartingValueLabel.Text = 'Estimated Starting Value';
            app.FinalValueLabel = uilabel(app.FittingParametersBand1Panel);
            app.FinalValueLabel.Text = 'Final Value';
            app.PeakLocationFinalValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.ShapeParameterFinalValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.AmplitudeFinalValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.SkewnessFinalValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.PeakLocationEditFieldLabel = uilabel(app.FittingParametersBand1Panel);
            app.PeakLocationEditFieldLabel.Text = 'Peak Location';
            app.PeakLocationStartingValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.ShapeParameterLabel = uilabel(app.FittingParametersBand1Panel);
            app.ShapeParameterLabel.Text = 'Shape Parameter';
            app.ShapeParameterStartingValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.ShapeParameterLabel_2 = uilabel(app.FittingParametersBand1Panel);
            app.ShapeParameterLabel_2.Text = 'Amplitude';
            app.AmplitudeStartingValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.ShapeParameterLabel_3 = uilabel(app.FittingParametersBand1Panel);
            app.ShapeParameterLabel_3.Text = 'Skewness Parameter';
            app.SkewnessStartingValue_Band1 = uieditfield(app.FittingParametersBand1Panel, 'text');
            app.FittingParametersBand2Panel = uipanel(app.FittingOptionsUIFigure);
            app.FittingParametersBand2Panel.Title = 'Fitting Parameters: Band 2';
            app.PeakLocationFinalValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.ShapeParameterFinalValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.AmplitudeFinalValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.SkewnessFinalValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.PeakLocationEditFieldLabel_3 = uilabel(app.FittingParametersBand2Panel);
            app.PeakLocationEditFieldLabel_3.Text = 'Peak Location';
            app.PeakLocationStartingValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.ShapeParameterLabel_7 = uilabel(app.FittingParametersBand2Panel);
            app.ShapeParameterLabel_7.Text = 'Shape Parameter';
            app.ShapeParameterStartingValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.ShapeParameterLabel_8 = uilabel(app.FittingParametersBand2Panel);
            app.ShapeParameterLabel_8.Text = 'Amplitude';
            app.AmplitudeStartingValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.ShapeParameterLabel_9 = uilabel(app.FittingParametersBand2Panel);
            app.ShapeParameterLabel_9.Text = 'Skewness Parameter';
            app.SkewnessStartingValue_Band2 = uieditfield(app.FittingParametersBand2Panel, 'text');
            app.EstimatedStartingValueLabel_2 = uilabel(app.FittingParametersBand2Panel);
            app.EstimatedStartingValueLabel_2.Text = 'Estimated Starting Value';
            app.FinalValueLabel_2 = uilabel(app.FittingParametersBand2Panel);
            app.FinalValueLabel_2.Text = 'Final Value';
            app.FittingParametersBand3Panel = uipanel(app.FittingOptionsUIFigure);
            app.FittingParametersBand3Panel.Title = 'Fitting Parameters: Band 3';
            app.PeakLocationFinalValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.ShapeParameterFinalValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.AmplitudeFinalValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.SkewnessFinalValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.PeakLocationEditFieldLabel_4 = uilabel(app.FittingParametersBand3Panel);
            app.PeakLocationEditFieldLabel_4.Text = 'Peak Location';
            app.PeakLocationStartingValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.ShapeParameterLabel_10 = uilabel(app.FittingParametersBand3Panel);
            app.ShapeParameterLabel_10.Text = 'Shape Parameter';
            app.ShapeParameterStartingValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.ShapeParameterLabel_11 = uilabel(app.FittingParametersBand3Panel);
            app.ShapeParameterLabel_11.Text = 'Amplitude';
            app.AmplitudeStartingValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.ShapeParameterLabel_12 = uilabel(app.FittingParametersBand3Panel);
            app.ShapeParameterLabel_12.Text = 'Skewness Parameter';
            app.SkewnessStartingValue_Band3 = uieditfield(app.FittingParametersBand3Panel, 'text');
            app.EstimatedStartingValueLabel_3 = uilabel(app.FittingParametersBand3Panel);
            app.EstimatedStartingValueLabel_3.Text = 'Estimated Starting Value';
            app.FinalValueLabel_3 = uilabel(app.FittingParametersBand3Panel);
            app.FinalValueLabel_3.Text = 'Final Value';
            % Show the figure after all components are created
            app.FittingOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingOptionsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingOptionsUIFigure)
        end
    end
end