classdef GelBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GelBoxUIFigure               matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadImageMenu                matlab.ui.container.Menu
        InvertImageMenu              matlab.ui.container.Menu
        LoadAnalysisMenu             matlab.ui.container.Menu
        SaveAnalysisMenu             matlab.ui.container.Menu
        ExportResultsMenu            matlab.ui.container.Menu
        DataAnalysisMenu             matlab.ui.container.Menu
        GelImageFileInformationMenu  matlab.ui.container.Menu
        SelectedBoxInformationMenu   matlab.ui.container.Menu
        SummaryPlotMenu              matlab.ui.container.Menu
        FittingPanel                 matlab.ui.container.Panel
        FittingParametersButton      matlab.ui.control.Button
        NumberofBandsDropDown        matlab.ui.control.DropDown
        NumberofBandsDropDownLabel   matlab.ui.control.Label
        BandRelativeArea_3           matlab.ui.control.NumericEditField
        BandRelativeAreaLabel_3      matlab.ui.control.Label
        BandArea_3                   matlab.ui.control.NumericEditField
        BandAreaLabel_3              matlab.ui.control.Label
        BandRelativeArea_2           matlab.ui.control.NumericEditField
        BandRelativeAreaLabel_2      matlab.ui.control.Label
        BandArea_2                   matlab.ui.control.NumericEditField
        BandAreaLabel_2              matlab.ui.control.Label
        BandRelativeArea_1           matlab.ui.control.NumericEditField
        BandRelativeAreaLabel_1      matlab.ui.control.Label
        BandArea_1                   matlab.ui.control.NumericEditField
        BandAreaLabel_1              matlab.ui.control.Label
        rsquaredField                matlab.ui.control.NumericEditField
        RsquaredLabel                matlab.ui.control.Label
        DrawFittingCheckBox          matlab.ui.control.CheckBox
        BackgroundCorrectedOpticalDensityLabel  matlab.ui.control.Label
        RawOpticalDensityLabel       matlab.ui.control.Label
        raw_density_fit              matlab.ui.control.UIAxes
        background_corrected_raw_density_fit  matlab.ui.control.UIAxes
        GelImagePanel                matlab.ui.container.Panel
        BoxSelectionDropDown         matlab.ui.control.DropDown
        BoxSelectionDropDownLabel    matlab.ui.control.Label
        DeleteBoxButton              matlab.ui.control.Button
        NewBoxButton                 matlab.ui.control.Button
        AdjustImageButton            matlab.ui.control.Button
        gel_image_axis               matlab.ui.control.UIAxes
        OpticalDensitiesPanel        matlab.ui.container.Panel
        BackgroundCorrAreaField      matlab.ui.control.NumericEditField
        BackgroundCorrAreaLabel      matlab.ui.control.Label
        BackgroundAreaField          matlab.ui.control.NumericEditField
        BackgroundAreaLabel          matlab.ui.control.Label
        TotalAreaField               matlab.ui.control.NumericEditField
        TotalAreaEditFieldLabel      matlab.ui.control.Label
        BackgroundCorrectedOpticalDensityLabel_2  matlab.ui.control.Label
        RawOpticalDensityLabel_2     matlab.ui.control.Label
        BoxZoomLabel                 matlab.ui.control.Label
        box_inset                    matlab.ui.control.UIAxes
        background_corrected_raw_density  matlab.ui.control.UIAxes
        raw_density                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        gel_data % Description
        fitting_options
        new_box = 0
        mode_updated = 0
        parameters_updated = 0
        box_changed = 0
        loaded_analysis = 0
        par_est_na = 0
        image_adjusted = 0
        single_box_callback
        d
        par_fit_na = 0 % Description
        moving_box = 0 % Description
    end

    properties (Access = private)
        ImageFileTextDialog % Description
        SelectedBoxTextDialog % Description
        FittingOptions % Description
        SummaryPlot
        AdjustImage
    end

    methods (Access = public)

        function UpdateDisplay(app)
            if (isfield(app.gel_data,'box_handle'))
                n = numel(app.gel_data.box_handle);
                t = size(app.gel_data.par_update,2);
                if t ~= n
                    for l = t+1:n
                        app.gel_data.par_update(l) = 0;
                    end
                end
                % Get selected box in control
                control_strings = app.BoxSelectionDropDown.Value;
                selected_box = str2num(control_strings);

                for i=1:n
                    p(i,1:4) = app.gel_data.box_handle(i).Position;
                end
                w = p(selected_box,3);
                h = p(selected_box,4);
                if ((w~=app.gel_data.old_width)|(h~=app.gel_data.old_height))
                    for i=1:n
                        p(i,3) = w;
                        p(i,4) = h;
                        app.gel_data.box_handle(i).Position = p(i,:);
                    end
                end

                % Store data in case we need to save it
                for i=1:n
                    app.gel_data.box_position(i,:) = app.gel_data.box_handle(i).Position;
                end

                if app.single_box_callback
                    disp_start = selected_box;
                    disp_end = selected_box;
                else
                    disp_start = 1;
                    disp_end = n;
                end
                
%                 app.d.box(selected_box).fitting_mode = str2num(app.NumberofBandsDropDown.Value);

                
                for i = disp_start:disp_end

%                     app.d.box(i).fitting_mode = str2num(app.NumberofBandsDropDown.Value);
                    num_of_bands = app.d.box(i).fitting_mode;
                    % Extract position
                    app.d.box(i).position = app.gel_data.box_handle(i).Position;

                    % Label it
                    set(app.gel_data.box_label(i),'String',sprintf('%.0f',i));
                    set(app.gel_data.box_label(i), ...
                        'Position',[app.d.box(i).position(1)+app.d.box(i).position(3)+10 ...
                        app.d.box(i).position(2)-50]);

                    % Calculate profile
                    app.d.box(i).inset = imcrop(app.gel_data.im_data, ...
                        app.d.box(i).position);
                    summary_position = [app.d.box(i).position(1)-10, app.d.box(i).position(2)-10, app.d.box(i).position(3)+20, app.d.box(i).position(4)+20];
                    app.d.box(i).summary_inset = imcrop(app.gel_data.im_data, ...
                        summary_position);
                    
                    m = imcomplement(app.d.box(i).inset);

                    x = flipud(mean(m,2));
                    y = 1:size(m,1);

                    x_back = linspace(x(1),x(end),numel(y));
                    box_no = str2num(app.BoxSelectionDropDown.Value);
                    
                    if (app.loaded_analysis && (app.new_box <= length(app.gel_data.par_est))) ...
                        || app.parameters_updated || app.gel_data.par_update(i) || app.moving_box
                    elseif app.new_box || app.mode_updated || app.par_est_na
                        [par_est,par_con] = EstimateFittingParameters(app,y,x, ...
                            x_back,num_of_bands);
                        fnames = fieldnames(par_est);
                        for k = 1:numel(fnames)
                            app.gel_data.par_est(i).(fnames{k}) = [];
                            app.gel_data.par_est(i).(fnames{k}) = ...
                                par_est.(fnames{k});
                            app.gel_data.par_con(i).(fnames{k}) = [];
                            app.gel_data.par_con(i).(fnames{k}) = ...
                                par_con.(fnames{k});
                        end
                    end

                    switch num_of_bands
                        case 1
                            app.BandRelativeArea_1.Enable = 0;
                            [x_bands,x_fit,r_squared,par_fit] = ...
                                FitGaussian(app,y,x,x_back,i);
                        case 2
                            if ~app.BandRelativeArea_1.Enable
                                app.BandRelativeArea_1.Enable = 1;
                            end
                            [x_bands,x_fit,r_squared,par_fit] = ...
                                Fit2Gaussian(app,y,x,x_back,i);
                            
                        case 3
                            if ~app.BandRelativeArea_1.Enable
                                app.BandRelativeArea_1.Enable = 1;
                            end
                            [x_bands,x_fit,r_squared,par_fit] = ...
                                Fit3Gaussian(app,y,x,x_back,i);
                    end
                    
                    fnames = fieldnames(par_fit);
                    for k = 1:numel(fnames)
                        app.gel_data.par_fit(i).(fnames{k}) = ...
                            [];
                        app.gel_data.par_fit(i).(fnames{k}) = ...
                            par_fit.(fnames{k});
                    end

                    app.d.box(i).total_area = trapz(y,x);
                    app.d.box(i).background_area = trapz(y,x_back);
                    app.d.box(i).background_corr_area = trapz(y,(x'-x_back));
                    
                    for j = 1 : num_of_bands
                        app.d.box(i).band_area(j) = trapz(y,x_bands(j,:));
                    end

                    % Store data for later

                    app.gel_data.box_data(i) = app.d.box(i);
                    app.gel_data.summary(i).x = x;
                    app.gel_data.summary(i).y = y;
                    app.gel_data.summary(i).x_fit = x_fit;
                    app.gel_data.summary(i).x_back = x_back;


                    if num_of_bands == 2
                        [~,peak_band_1] = max(x_bands(1,:));
                        [~,peak_band_2] = max(x_bands(2,:));

                        if peak_band_2 > peak_band_1 || ~any(x_bands(2,:)>0)

                            app.gel_data.summary(i).bottom = app.d.box(i).band_area(1);
                            app.gel_data.summary(i).top = app.d.box(i).band_area(2);
                            app.gel_data.summary(i).band_1 = x_bands(1,:);
                            app.gel_data.summary(i).band_2 = x_bands(2,:);
                            app.gel_data.summary(i).band_3 = NaN*ones(1,numel(x_bands(1,:)));
                        else
                            app.gel_data.summary(i).bottom = app.d.box(i).band_area(2);
                            app.gel_data.summary(i).top = app.d.box(i).band_area(1);
                            app.gel_data.summary(i).band_1 = x_bands(2,:);
                            app.gel_data.summary(i).band_2 = x_bands(1,:);
                            app.gel_data.summary(i).band_3 = NaN*ones(1,numel(x_bands(1,:)));
                        end
                    elseif num_of_bands == 3
                        [~,peak_band_1] = max(x_bands(1,:));
                        [~,peak_band_2] = max(x_bands(2,:));
                        [~,peak_band_3] = max(x_bands(3,:));

                        peak_band = [peak_band_1 peak_band_2 peak_band_3];

                        %sort
                        [~,sort_ix] = sort(peak_band);
                        app.gel_data.summary(i).bottom = app.d.box(i).band_area( ...
                            sort_ix(1));
                        app.gel_data.summary(i).middle = app.d.box(i).band_area( ...
                            sort_ix(2));
                        app.gel_data.summary(i).top = app.d.box(i).band_area( ...
                            sort_ix(3));
                        app.gel_data.summary(i).band_1 = x_bands( ...
                            sort_ix(1),:);
                        app.gel_data.summary(i).band_2 = x_bands( ...
                            sort_ix(2),:);
                        app.gel_data.summary(i).band_3 = x_bands( ...
                            sort_ix(3),:);
                    else
                        app.gel_data.summary(i).band_1 = x_bands(1,:);
                        app.gel_data.summary(i).band_2 = NaN*ones(1,numel(x_bands(1,:)));
                        app.gel_data.summary(i).band_3 = NaN*ones(1,numel(x_bands(1,:)));
                        app.gel_data.summary(i).bottom = app.d.box(i).band_area;
                    end

                    app.gel_data.summary(i).inset = app.d.box(i).inset;
                    app.gel_data.summary(i).summary_inset = app.d.box(i).summary_inset;
                    app.gel_data.summary(i).r_squared = r_squared;
                    app.gel_data.summary(i).box_position = app.d.box(i).position;

                    % Display
                    if (i==selected_box)
                        app.TotalAreaField.Value = app.d.box(i).total_area;
                        app.BackgroundAreaField.Value = app.d.box(i).background_area;
                        app.BackgroundCorrAreaField.Value = app.d.box(i).background_corr_area;
                        app.rsquaredField.Value = r_squared;
                        center_image_with_preserved_aspect_ratio( ...
                            app.d.box(i).inset, ...
                            app.box_inset,[]);
                        cla(app.raw_density)
                        plot(app.raw_density,x,y,"Color",'k',"LineWidth",2)
                        hold(app.raw_density,"on")
                        plot(app.raw_density,x_back,y,'-.m',"LineWidth",2)
                        x_pow = ceil(log10(max(x)));
                        x_tick_rounder = 10^(x_pow - 1);
                        x_t_end = ceil(max(x)/x_tick_rounder)*x_tick_rounder;
                        x_t_mid = round(x_t_end/2);
                        x_ticks = [0 x_t_mid x_t_end];
                        xticks(app.raw_density,x_ticks)
                        app.raw_density.XAxis.Exponent = 0;
                        xlim(app.raw_density,[0 x_t_end]);
                        ylim(app.raw_density,[1 max(y)]);
                        legend(app.raw_density,'','Baseline', ...
                            'Location','northeast')


                        xticks(app.raw_density_fit,x_ticks);
                        app.raw_density_fit.XAxis.Exponent = 0;

                        xlim(app.raw_density_fit,[0 x_t_end]);
                        ylim(app.raw_density_fit,[1 max(y)]);
                        % legend(app.raw_density)


                        cla(app.background_corrected_raw_density)
                        plot(app.background_corrected_raw_density, ...
                            x-x_back',y,'-.k',"LineWidth",2)
                        hold(app.background_corrected_raw_density,"on")
                        plot(app.background_corrected_raw_density, ...
                            zeros(1,numel(y)),y,'-.m',"LineWidth",2)
                        legend(app.background_corrected_raw_density,'','Baseline', ...
                            'Location','northeast')
                        ylim(app.background_corrected_raw_density, ...
                            [1 max(y)]);

                        x_pow = ceil(log10(max(x-x_back')));
                        x_tick_rounder = 10^(x_pow - 1);
                        x_t_end = ceil(max(x-x_back')/x_tick_rounder)*x_tick_rounder;
                        if min(x-x_back') < 0
                            x_tick_rounder = -10^(x_pow - 2)*0.25;
                        else
                            x_tick_rounder = 10^(x_pow - 2)*0.25;
                        end
                        x_t_beginning = ceil(min(x-x_back')/x_tick_rounder)*x_tick_rounder;
                        x_t_mid = round(x_t_end/2);
                        x_ticks = [x_t_beginning x_t_mid x_t_end];

                        app.background_corrected_raw_density.XAxis.Exponent = 0;
                        xticks(app.background_corrected_raw_density,x_ticks)
                        xlim(app.background_corrected_raw_density,[x_t_beginning x_t_end])
                        ylim(app.background_corrected_raw_density,[1 max(y)]);

                        xticks(app.background_corrected_raw_density_fit,x_ticks)
                        app.background_corrected_raw_density_fit.XAxis.Exponent = 0;
                        xlim(app.background_corrected_raw_density_fit,[x_t_beginning x_t_end])
                        ylim(app.background_corrected_raw_density_fit,[1 max(y)]);

                        switch num_of_bands
                            case 1
                                color = {'r'};
                                app.BandArea_1.Value = ...
                                    app.gel_data.summary(i).bottom;
                            case 2
                                color = {'r','b'};
                                total_band = app.gel_data.summary(i).bottom ...
                                    + app.gel_data.summary(i).top;
                                app.BandArea_1.Value = ...
                                    app.gel_data.summary(i).bottom;

                                app.BandRelativeArea_1.Value = ...
                                    app.gel_data.summary(i).bottom/total_band;

                                app.BandArea_2.Value = ...
                                    app.gel_data.summary(i).top;

                                app.BandRelativeArea_2.Value = ...
                                    app.gel_data.summary(i).top/total_band;
                            case 3
                                color = {'r','b','y'};
                                total_band = app.gel_data.summary(i).bottom ...
                                    + app.gel_data.summary(i).middle ...
                                    + app.gel_data.summary(i).top;

                                app.BandArea_1.Value = ...
                                    app.gel_data.summary(i).bottom;

                                app.BandRelativeArea_1.Value = ...
                                    app.gel_data.summary(i).bottom/total_band;

                                app.BandArea_2.Value = ...
                                    app.gel_data.summary(i).middle;

                                app.BandRelativeArea_2.Value = ...
                                    app.gel_data.summary(i).middle/total_band;

                                app.BandArea_3.Value = ...
                                    app.gel_data.summary(i).top;

                                app.BandRelativeArea_3.Value = ...
                                    app.gel_data.summary(i).top/total_band;
                        end


                        cla(app.raw_density_fit)
                        cla(app.background_corrected_raw_density_fit)


                        for j = 1 : num_of_bands
%                             patch(app.raw_density_fit, ...
%                                 x_back+x_bands(j,:), ...
%                                 y,color{j},'FaceAlpha',0.65, ...
%                                 'EdgeColor',color{j},'EdgeAlpha',0.25, ...
%                                 'LineWidth',2)
                            patch(app.background_corrected_raw_density_fit, ...
                                x_bands(j,:), ...
                                y,color{j},'FaceAlpha',0.65, ...
                                'EdgeColor',color{j},'EdgeAlpha',0.25, ...
                                'LineWidth',2)
                        end

                        f_color = '#1aff00';

                        hold(app.background_corrected_raw_density_fit,"on")
                        plot(app.background_corrected_raw_density_fit, ...
                            x-x_back',y,'-.k',"LineWidth",2)
                        plot(app.background_corrected_raw_density_fit, ...
                            x_fit,y,':',"LineWidth",2,"Color",f_color)

                        hold(app.raw_density_fit,"on")

                        plot(app.raw_density_fit, ...
                            x,y,"Color",'k',"LineWidth",2)
                        plot(app.raw_density_fit, ...
                            x_back+x_fit,y,':',"LineWidth",2,"Color",f_color)

                        ylim(app.raw_density_fit, ...
                            [1 max(y)]);
                        ylim(app.background_corrected_raw_density_fit, ...
                            [1 max(y)]);
                    end
                end
                app.gel_data.d_box = app.d;
            end
        end

        function [y_bands, y_fit,r_squared,par_fit] = FitGaussian(app,x,y,y_back,box_no)
            
            par_est = struct();
            par_con = struct();
            
            box_pars = struct();
            box_pars = app.gel_data.par_est(box_no);
            
            box_cons = struct();
            box_cons = app.gel_data.par_con(box_no);
            
            names = fieldnames(box_pars);
            for m = 1 : numel(names)
                par_est.(names{m}) = box_pars.(names{m});
                par_con.(names{m}) = box_cons.(names{m});

            end
            
            par = [par_est.peak_location(1) ...
                par_est.amplitude(1) ...
                par_est.width_parameter(1) ...
                par_est.skew_parameter(1) ...
                ];
            
            target = y';

            target = target - y_back;

            j = 1;
            e = [];
            
            no_of_parameters = numel(par);
            
            A_constraints = zeros(1,no_of_parameters);
            A_constraints(1) = 1;
            B_constants = [0];
            
            lower_bounds=zeros(1,no_of_parameters);
            upper_bounds=Inf*ones(1,no_of_parameters);
            
            for m = 2 : numel(names)        
                if par_con.(names{m})
                    lower_bounds(m-1) = par_est.(names{m});
                    upper_bounds(m-1) = par_est.(names{m});
                end             
            end
          
            opts=optimset('fminsearch');
            opts.Display='off';
            opts.MaxIter=1000;
            opts.MaxFunEvals=10000;
            
            [p_result,fval,exitflag,output]= ...
                fminsearchcon(@profile_error_1gaussian,par, ...
                lower_bounds,upper_bounds,[],[], ...
                [],opts);
                        
            par_fit.band_no = [1];
            par_fit.peak_location(1,1) = p_result(1);
            par_fit.amplitude(1,1) = p_result(2);
            par_fit.width_parameter(1,1) = p_result(3);
            par_fit.skew_parameter(1,1) = p_result(4);
            
            r_squared = calculate_r_squared(y',y_fit+y_back);
            
            function trial_e = profile_error_1gaussian(par)

                [y_bands,y_fit] = calculate_profile(x,par);

                e(j) = 0;

                for i  = 1 : numel(target)
                    e(j) = e(j) + (y_fit(i) - target(i))^2;
                end

                trial_e = e(end);

                for i = 1:1
                    if any(y_bands(i,:)<0)
                        trial_e = trial_e + 10^12;
                    end
                end

                if any(par<0)
                    trial_e = trial_e + 10^12;
                end

                for i = 1:1
                    areas(i) = trapz(x,y_bands(i,:));
                end

                if any(areas<0)
                    trial_e = trial_e + 10^12;
                end

               positions = [par(1)];

                if any(positions>numel(x))
                    trial_e = trial_e + 10^12;
                end
                
                r_squared_iter = calculate_r_squared(target,y_fit);
                j = j + 1;
                if app.DrawFittingCheckBox.Value
                    cla(app.background_corrected_raw_density_fit)
                    plot(app.background_corrected_raw_density_fit,y_fit,x,'LineWidth',2)
                    hold(app.background_corrected_raw_density_fit, 'on')
                    plot(app.background_corrected_raw_density_fit,target,x,'LineWidth',2,'LineStyle','-.','Color','k')
                    app.rsquaredField.Value = r_squared_iter;
                    drawnow
                end
            end
            function [y_bands,y_fit] = calculate_profile(x,par)

                x1 = par(1);
                amp1 = par(2);
                width1 = par(3);
                skew1 = par(4);

                y_first = skewed_Gaussian(x,x1,width1,amp1,skew1);

                y_fit = y_first;
                y_bands(1,:) = y_first;

            end
            function y=skewed_Gaussian(x,x0,gamma,A,skew1)
                offset = zeros(1,length(x));
                offset((x-x0)>0) = skew1*(x((x-x0)>0)-x0);
                y=  A*exp(-gamma*(((x-x0)+offset).^2));
            end


        end

        function [y_bands, y_fit,r_squared,par_fit] = Fit2Gaussian(app,x,y,...
                y_back,box_no)
            par_est = struct();
            par_con = struct();
            
            box_pars = struct();
            box_pars = app.gel_data.par_est(box_no);
            
            box_cons = struct();
            box_cons = app.gel_data.par_con(box_no);
            
            names = fieldnames(box_pars);
            for m = 1 : numel(names)
                par_est.(names{m}) = box_pars.(names{m});
                par_con.(names{m}) = box_cons.(names{m});

            end

            par = [par_est.peak_location(1) ...
                   par_est.amplitude(1) ...
                   par_est.width_parameter(1)...
                   par_est.skew_parameter(1) ...
                   par_est.peak_location(2) ...
                   par_est.amplitude(2) ...
                  ];
            
            width_and_skew = 0;
            width = 0;
            skew = 0;
            
            if (any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (any(par_con.width_parameter)&& ...
                    any(par_con.skew_parameter))
                par(7) = par_est.width_parameter(2);
                par(8) = par_est.skew_parameter(2);
                width_and_skew = 1;
            elseif (any(par_est.width_parameter(2:end)~=0) && ...
                    ~any(par_est.skew_parameter(2:end)~=0))|| ...
                    (any(par_con.width_parameter) && ...
                    ~any(par_con.skew_parameter))
                par(7) = par_est.width_parameter(2);
                width = 1;
            elseif (~any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (~any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                par(7) = par_est.skew_parameter(2);
                skew = 1;
            end


            j = 1;
            e = [];

            target = y';

            target = target - y_back;
            
            no_of_parameters = numel(par);
            
            A_constraints = zeros(1,no_of_parameters);
            A_constraints(1) = 1;
            B_constants = [0];
            
            lower_bounds=zeros(1,no_of_parameters);
            upper_bounds=Inf*ones(1,no_of_parameters);
            if no_of_parameters > 6
                for t = 7:numel(par)
                    lower_bounds(t)=-inf;
                end
            end
            
            no_of_bands = numel(par_con.(names{m}));
          
            l = 1;
            for u = 1 : 1
                for m = 2 : numel(names)
                    if par_con.(names{m})(u)
                        lower_bounds(l) = par_est.(names{m})(u);
                        upper_bounds(l) = par_est.(names{m})(u);
                    end
                    l = l + 1;
                end
            end
            
            if par_con.peak_location(2)
                lower_bounds(5) = par_est.peak_location(2);
                upper_bounds(5) = par_est.peak_location(2);
            end
            
            if par_con.amplitude(2)
                lower_bounds(6) = par_est.amplitude(2);
                upper_bounds(6) = par_est.amplitude(2);
            end
            
            if width_and_skew
                if par_con.width_parameter(2)
                    lower_bounds(7) = par_est.width_parameter(2);
                    upper_bounds(7) = par_est.width_parameter(2);
                end
                if par_con.skew_parameter(2)
                    lower_bounds(8) = par_est.skew_parameter(2);
                    upper_bounds(8) = par_est.skew_parameter(2);
                end
            end
            
            if width
                if par_con.width_parameter(2)
                    lower_bounds(7) = par_est.width_parameter(2);
                    upper_bounds(7) = par_est.width_parameter(2);
                end
            end
            
            if skew
                if par_con.skew_parameter(2)
                    lower_bounds(7) = par_est.skew_parameter(2);
                    upper_bounds(7) = par_est.skew_parameter(2);
                end
            end
                        
            opts=optimset('fminsearch');
            opts.Display='off';
            opts.MaxIter=1000;
            opts.MaxFunEvals=10000;
            
            [p_result,fval,exitflag,output]= ...
                fminsearchcon(@profile_error_2gaussian,par, ...
                lower_bounds,upper_bounds,[],[], ...
                [],opts);
            
            par_fit.band_no = [1;2];
            par_fit.peak_location(1,1) = p_result(1);
            par_fit.amplitude(1,1) = p_result(2);
            par_fit.width_parameter(1,1) = p_result(3);
            par_fit.skew_parameter(1,1) = p_result(4);
            par_fit.peak_location(2,1) = p_result(5);
            par_fit.amplitude(2,1) = p_result(6);
            
            if (any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                par_fit.width_parameter(2,1) = p_result(7);
                par_fit.skew_parameter(2,1) = p_result(8);
            elseif (any(par_est.width_parameter(2:end)~=0) && ...
                    ~any(par_est.skew_parameter(2:end)~=0)) || ...
                    (any(par_con.width_parameter) && ...
                    ~any(par_con.skew_parameter))
                par_fit.width_parameter(2,1) = p_result(7);
                par_fit.skew_parameter(2,1) = 0;
            elseif (~any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (~any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                par_fit.width_parameter(2,1) = 0;
                par_fit.skew_parameter(2,1) = p_result(7);
            else
                par_fit.width_parameter(2,1) = 0;
                par_fit.skew_parameter(2,1) = 0;
            end

            r_squared = calculate_r_squared(y',y_fit+y_back);
            function trial_e = profile_error_2gaussian(par)

                [y_bands,y_fit] = calculate_2profile(x,par);

                e(j) = 0;

                for i  = 1 : numel(target)
                    e(j) = e(j) + (y_fit(i) - target(i))^2;
                end

                trial_e = e(end);

                for i = 1:2
                    if any(y_bands(i,:)<0)
                        trial_e = trial_e + 10^12;
                    end
                end

                if any(par<0)
                    trial_e = trial_e + 10^12;
                end

                for i = 1:2
                    areas(i) = trapz(x,y_bands(i,:));
                end

                if any(areas<0)
                    trial_e = trial_e + 10^12;
                end

               positions = [par(1) par(5)];

                if any(positions>numel(x))
                    trial_e = trial_e + 10^12;
                end

                r_squared_iter = calculate_r_squared(target,y_fit);
                j = j + 1;
                if app.DrawFittingCheckBox.Value
                    cla(app.background_corrected_raw_density_fit)
                    plot(app.background_corrected_raw_density_fit, ...
                        y_fit,x,'LineWidth',2)
                    hold(app.background_corrected_raw_density_fit, 'on')
                    plot(app.background_corrected_raw_density_fit, ...
                        target,x,'LineWidth',2,'LineStyle','-.','Color','k')
                    app.rsquaredField.Value = r_squared_iter;
                    drawnow
                end
            end
            function [y_bands,y_fit] = calculate_2profile(x,par)

                x1 = par(1);
                amp1 = par(2);
                width1 = par(3);
                skew1 = par(4);
                x2 = par(5);
                amp2 = par(6);

                if (any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                    width2 = width1 + par(7);
                    skew2 = skew1 + par(8);
                elseif (any(par_est.width_parameter(2:end)~=0) && ...
                    ~any(par_est.skew_parameter(2:end)~=0)) || ...
                    (any(par_con.width_parameter) && ...
                    ~any(par_con.skew_parameter))
                    width2 = width1 + par(7);
                    skew2 = skew1 + 0;
                elseif (~any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (~any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                    width2 = width1 + 0;
                    skew2 = skew1 + par(7);
                else
                    width2 = width1 + 0;
                    skew2 = skew1 + 0;
                end

                y_first = skewed_Gaussian(x,x1,width1,amp1,skew1);
                y_second = skewed_Gaussian(x,x2,width2,amp2,skew2);

                y_fit = y_first + y_second;
                y_bands(1,:) = y_first;
                y_bands(2,:) = y_second;

            end
            function y=skewed_Gaussian(x,x0,gamma,A,skew1)
                offset = zeros(1,length(x));
                offset((x-x0)>0) = skew1*(x((x-x0)>0)-x0);
                y=  A*exp(-gamma*(((x-x0)+offset).^2));
            end
        end

        function [y_bands, y_fit,r_squared,par_fit] = Fit3Gaussian(app,x,y,y_back,box_no)
            
            par_est = struct();
            par_con = struct();
            
            box_pars = struct();
            box_pars = app.gel_data.par_est(box_no);
            
            box_cons = struct();
            box_cons = app.gel_data.par_con(box_no);
            
            names = fieldnames(box_pars);
            for m = 1 : numel(names)
                par_est.(names{m}) = box_pars.(names{m});
                par_con.(names{m}) = box_cons.(names{m});

            end
            
            par = [par_est.peak_location(1) ...
                par_est.amplitude(1) ...
                par_est.width_parameter(1)...
                par_est.skew_parameter(1) ...
                par_est.peak_location(2) ...
                par_est.amplitude(2) ...
                par_est.peak_location(3) ...
                par_est.amplitude(3) ...
                ];
            
            width_and_skew = 0;
            width = 0;
            skew = 0;
            
            if (any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0))  || ...
                    (any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                par(9) = par_est.width_parameter(2);
                par(10) = par_est.skew_parameter(2);
                par(11) = par_est.width_parameter(3);
                par(12) = par_est.skew_parameter(3);
                width_and_skew = 1;
            elseif (any(par_est.width_parameter(2:end)~=0) && ...
                    ~any(par_est.skew_parameter(2:end)~=0))  || ...
                    (any(par_con.width_parameter) && ...
                    ~any(par_con.skew_parameter))
                par(9) = par_est.width_parameter(2);
                par(10) = par_est.width_parameter(3);
                width = 1;
            elseif (~any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0))  || ...
                    (~any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                par(9) = par_est.skew_parameter(2);
                par(10) = par_est.skew_parameter(3);
                skew = 1;
            end

            
            j = 1;
            e = [];
            

            target = y';

            target = target - y_back;

            no_of_parameters = numel(par);
            
            A_constraints = zeros(1,no_of_parameters);
            A_constraints(1) = 1;
            B_constants = [0];
            
            lower_bounds=zeros(1,no_of_parameters);
            upper_bounds=Inf*ones(1,no_of_parameters);
            if no_of_parameters > 6
                for t = 7:numel(par)
                    lower_bounds(t)=-inf;
                end
            end
            no_of_bands = numel(par_con.(names{m}));
            
            l = 1;
            for u = 1 : 1
                for m = 2 : numel(names)
                    if par_con.(names{m})(u)
                        lower_bounds(l) = par_est.(names{m})(u);
                        upper_bounds(l) = par_est.(names{m})(u);
                    end
                    l = l + 1;
                end
            end
            
            if par_con.peak_location(2)
                lower_bounds(5) = par_est.peak_location(2);
                upper_bounds(5) = par_est.peak_location(2);
            end
            
            if par_con.amplitude(2)
                lower_bounds(6) = par_est.amplitude(2);
                upper_bounds(6) = par_est.amplitude(2);
            end
            
            if par_con.peak_location(3)
                lower_bounds(7) = par_est.peak_location(3);
                upper_bounds(7) = par_est.peak_location(3);
            end
            
            if par_con.amplitude(3)
                lower_bounds(8) = par_est.amplitude(3);
                upper_bounds(8) = par_est.amplitude(3);
            end
            
            if width_and_skew
                if par_con.width_parameter(2)
                    lower_bounds(9) = par_est.width_parameter(2);
                    upper_bounds(9) = par_est.width_parameter(2);
                end
                if par_con.skew_parameter(2)
                    lower_bounds(10) = par_est.skew_parameter(2);
                    upper_bounds(10) = par_est.skew_parameter(2);
                end
                if par_con.width_parameter(3)
                    lower_bounds(11) = par_est.width_parameter(2);
                    upper_bounds(11) = par_est.width_parameter(2);
                end
                if par_con.skew_parameter(3)
                    lower_bounds(12) = par_est.skew_parameter(2);
                    upper_bounds(11) = par_est.skew_parameter(2);
                end
            end
            
            if width
                if par_con.width_parameter(2)
                    lower_bounds(9) = par_est.width_parameter(2);
                    upper_bounds(9) = par_est.width_parameter(2);
                end
                if par_con.width_parameter(3)
                    lower_bounds(10) = par_est.width_parameter(3);
                    upper_bounds(10) = par_est.width_parameter(3);
                end
            end

            if skew
                if par_con.skew_parameter(2)
                    lower_bounds(9) = par_est.skew_parameter(2);
                    upper_bounds(9) = par_est.skew_parameter(2);
                end

                if par_con.skew_parameter(3)
                    lower_bounds(10) = par_est.skew_parameter(2);
                    upper_bounds(10) = par_est.skew_parameter(2);
                end
            end
            

            opts=optimset('fminsearch');
            opts.Display='off';
            opts.MaxIter=1000;
            opts.MaxFunEvals=10000;
            
            [p_result,fval,exitflag,output]= ...
                fminsearchcon(@profile_error_3gaussian,par, ...
                lower_bounds,upper_bounds,[],[], ...
                [],opts);
            
            par_fit.band_no = [1;2;3];
            par_fit.peak_location(1,1) = p_result(1);
            par_fit.amplitude(1,1) = p_result(2);
            par_fit.width_parameter(1,1) = p_result(3);
            par_fit.skew_parameter(1,1) = p_result(4);
            par_fit.peak_location(2,1) = p_result(5);
            par_fit.amplitude(2,1) = p_result(6);
            par_fit.peak_location(3,1) = p_result(7);
            par_fit.amplitude(3,1) = p_result(8);
            
            if (any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0))  || ...
                    (any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                par_fit.width_parameter(2,1) = p_result(9);
                par_fit.skew_parameter(2,1) = p_result(10);
                par_fit.width_parameter(3,1) = p_result(11);
                par_fit.skew_parameter(3,1) = p_result(12);
            elseif (any(par_est.width_parameter(2:end)~=0) && ...
                    ~any(par_est.skew_parameter(2:end)~=0))  || ...
                    (any(par_con.width_parameter) && ...
                    ~any(par_con.skew_parameter))
                par_fit.width_parameter(2,1) = p_result(9);
                par_fit.skew_parameter(2,1) = 0;
                par_fit.width_parameter(3,1) = p_result(10);
                par_fit.skew_parameter(3,1) = 0;
            elseif (~any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0))  || ...
                    (~any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                par_fit.width_parameter(2,1) = 0;
                par_fit.skew_parameter(2,1) = p_result(9);
                par_fit.width_parameter(3,1) = 0;
                par_fit.skew_parameter(3,1) = p_result(10);
            else
                par_fit.width_parameter(2,1) = 0;
                par_fit.skew_parameter(2,1) = 0;
                par_fit.width_parameter(3,1) = 0;
                par_fit.skew_parameter(3,1) = 0;
                
            end

            r_squared = calculate_r_squared(y',y_fit+y_back);
            function trial_e = profile_error_3gaussian(par)
                [y_bands,y_fit] = calculate_3profile(x,par);
                
                e(j) = 0;
                for i  = 1 : numel(target)
                    e(j) = e(j) + (y_fit(i) - target(i))^2;
                end

                trial_e = e(end);

                for i = 1:3
                    if any(y_bands(i,:)<0)
                        trial_e = trial_e + 10^12;
                    end
                end

                if any(par<0)
                    trial_e = trial_e + 10^12;
                end

                for i = 1:3
                    areas(i) = trapz(x,y_bands(i,:));
                end

                if any(areas<0)
                    trial_e = trial_e + 10^12;
                end

                positions = [par(1) par(5) par(7)];

                if any(positions>numel(x))
                    trial_e = trial_e + 10^12;
                end

                r_squared_iter = calculate_r_squared(target,y_fit);
                j = j + 1;
                if app.DrawFittingCheckBox.Value
                    cla(app.background_corrected_raw_density_fit)
                    plot(app.background_corrected_raw_density_fit,y_fit,x,'LineWidth',2)
                    hold(app.background_corrected_raw_density_fit, 'on')
                    plot(app.background_corrected_raw_density_fit,target,x,'LineWidth',2,'LineStyle','-.','Color','k')
                    app.rsquaredField.Value = r_squared_iter;
                    drawnow
                end
            end
            function [y_bands,y_fit] = calculate_3profile(x,par)

                x1 = par(1);
                amp1 = par(2);
                width1 = par(3);
                skew1 = par(4);
                x2 = par(5);
                amp2 = par(6);
                x3 = par(7);
                amp3 = par(8);

                if (any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                    width2 = width1 + par(9);
                    skew2 = skew1 + par(10);
                    width3 = width1 + par(11);
                    skew3 = skew1 + par(12);
                elseif (any(par_est.width_parameter(2:end)~=0) && ...
                    ~any(par_est.skew_parameter(2:end)~=0))|| ...
                    (any(par_con.width_parameter) && ...
                    ~any(par_con.skew_parameter))
                    width2 = width1 + par(9);
                    skew2 = skew1 + 0;
                    width3 = width1 + par(10);
                    skew3 = skew1 + 0;
                elseif (~any(par_est.width_parameter(2:end)~=0) && ...
                    any(par_est.skew_parameter(2:end)~=0)) || ...
                    (~any(par_con.width_parameter) && ...
                    any(par_con.skew_parameter))
                    width2 = width1 + 0;
                    skew2 = skew1 + par(9);
                    width3 = width1 + 0;
                    skew3 = skew1 + par(10);
                else
                    width2 = width1 + 0;
                    skew2 = skew1 + 0;
                    width3 = width1 + 0;
                    skew3 = skew1 + 0;
                end

                y_first = skewed_Gaussian(x,x1,width1,amp1,skew1);
                y_second = skewed_Gaussian(x,x2,width2,amp2,skew2);
                y_third = skewed_Gaussian(x,x3,width3,amp3,skew3);


                y_fit = y_first + y_second + y_third;
                y_bands(1,:) = y_first;
                y_bands(2,:) = y_second;
                y_bands(3,:) = y_third;

            end
            function y=skewed_Gaussian(x,x0,gamma,A,skew1)
                offset = zeros(1,length(x));
                offset((x-x0)>0) = skew1*(x((x-x0)>0)-x0);
                y=  A*exp(-gamma*(((x-x0)+offset).^2));
            end
        end

        function UpdateFittingOptions(app)
            app.parameters_updated = 1;
            UpdateDisplay(app)
            app.parameters_updated = 0;
        end
        
        function [par_est,par_con] = EstimateFittingParameters(app,x,y,y_back, ...
                no_of_bands)

            switch no_of_bands
                case 1 

                    peaks=find_peaks('x',x, ...
                        'y',y, ...
                        'min_rel_delta_y',0.05, ...
                        'min_x_index_spacing',2);

                     if numel(peaks.max_indices) == no_of_bands
                         first_curve_x_estimate=peaks.max_indices(1);
                     else
                         first_curve_x_estimate = 0.5*length(x);
                     end

                     target = y';

                     target = target - y_back;
                     [max_value,~]=max(target);

                     half_distance=(0.1*length(x));
                     alfa_estimate = -log(0.5)/(half_distance^2);

                     first_curve_width_estimate = alfa_estimate;
                     first_curve_amp_estimate = max_value;
                     first_curve_skew_estimate = 1;


                     par_est.band_no  = 1;
                     par_est.peak_location = first_curve_x_estimate;
                     par_est.amplitude = first_curve_amp_estimate;
                     par_est.width_parameter = first_curve_width_estimate;
                     par_est.skew_parameter = first_curve_skew_estimate;
                     
                     par_con.band_no  = 1;
                     par_con.peak_location = false;
                     par_con.amplitude = false;
                     par_con.width_parameter = false;
                     par_con.skew_parameter = false;

                case 2

                    peaks=find_peaks('x',x, ...
                        'y',y, ...
                        'min_rel_delta_y',0.05, ...
                        'min_x_index_spacing',2);

                    if numel(peaks.max_indices) == no_of_bands
                        first_curve_x_estimate=peaks.max_indices(1);
                        second_curve_x_estimate=peaks.max_indices(2);
                    else
                        first_curve_x_estimate = 0.3*length(x);
                        second_curve_x_estimate = 0.6*length(x);
                    end

                    target = y';

                    target = target - y_back;
                    [max_value,~]=max(target);

                    half_distance=(0.1*length(x));
                    alfa_estimate = -log(0.5)/(half_distance^2);

                    first_curve_width_estimate = alfa_estimate;
                    first_curve_amp_estimate = max_value;
                    first_curve_skew_estimate = 1;

                    second_curve_amp_estimate = first_curve_amp_estimate;
                    second_curve_width_offset = 0;
                    second_curve_skew_offset = 0;

                     par_est.band_no  = [1;2];
                     par_est.peak_location = ...
                     [first_curve_x_estimate;second_curve_x_estimate];
                     par_est.amplitude = ...
                         [first_curve_amp_estimate;second_curve_amp_estimate];
                     par_est.width_parameter = ...
                     [first_curve_width_estimate;second_curve_width_offset];
                     par_est.skew_parameter = ...
                     [first_curve_skew_estimate;second_curve_skew_offset];
                     
                     par_con.band_no  = [1;2];
                     par_con.peak_location = [false;false];
                     par_con.amplitude = [false;false];
                     par_con.width_parameter = [false;false];
                     par_con.skew_parameter = [false;false];

                case 3
                    peaks=find_peaks('x',x, ...
                        'y',y, ...
                        'min_rel_delta_y',0.05, ...
                        'min_x_index_spacing',2);
                    if numel(peaks.max_indices) == no_of_bands
                        first_curve_x_estimate=peaks.max_indices(1);
                        second_curve_x_estimate=peaks.max_indices(2);
                        third_curve_x_estimate=peaks.max_indices(3);
                    else
                        first_curve_x_estimate = 0.2*length(x);
                        second_curve_x_estimate = 0.3*length(x);
                        third_curve_x_estimate = 0.6*length(x);
                    end
                    target = y';

                    target = target - y_back;
                    [max_value,~]=max(target);

                    half_distance=(0.1*length(x));
                    alfa_estimate = -log(0.5)/(half_distance^2);

                    first_curve_width_estimate = alfa_estimate;
                    first_curve_amp_estimate = max_value;
                    first_curve_skew_estimate = 1;

                    second_curve_amp_estimate = first_curve_amp_estimate;
                    second_curve_width_estimate = 0;
                    second_curve_skew_estimate = 0;

                    third_curve_amp_estimate = first_curve_amp_estimate;
                    third_curve_width_estimate = 0;
                    third_curve_skew_estimate = 0;

                    par_est.band_no  = [1;2;3];
                    par_est.peak_location = ...
                        [first_curve_x_estimate;...
                        second_curve_x_estimate;...
                        third_curve_x_estimate];
                    par_est.amplitude = ...
                        [first_curve_amp_estimate;...
                        second_curve_amp_estimate;...
                        third_curve_amp_estimate];
                    par_est.width_parameter = ...
                        [first_curve_width_estimate;...
                        second_curve_width_estimate;...
                        third_curve_width_estimate];
                    par_est.skew_parameter = ...
                        [first_curve_skew_estimate;...
                        second_curve_skew_estimate;...
                        third_curve_skew_estimate];
                    
                     par_con.band_no  = [1;2;3];
                     par_con.peak_location = [false;false;false];
                     par_con.amplitude = [false;false;false];
                     par_con.width_parameter = [false;false;false];
                     par_con.skew_parameter = [false;false;false];
            end


        end
        
        function ImageAdjusted(app)
                if ~isempty(app.gel_data.adjusted_image)
                    app.gel_data.im_data = app.gel_data.adjusted_image;
                    app.image_adjusted = 1;
                    center_image_with_preserved_aspect_ratio( ...
                        app.gel_data.im_data, ...
                        app.gel_image_axis,[]);
                end
                UpdateDisplay(app)
        end
    end

    methods (Access = private)

        function ResetDisplay(app)

            % Clear the figure displays
            cla(app.box_inset)
            cla(app.raw_density)
            cla(app.background_corrected_raw_density)
            cla(app.raw_density_fit)
            cla(app.background_corrected_raw_density_fit)

            % Reset optical density fields
            app.TotalAreaField.Value = 0;
            app.BackgroundAreaField.Value = 0;

            % Reset fitting fields
            app.DrawFittingCheckBox.Value = 0;
            app.rsquaredField.Value = 0;
            app.BandArea_1.Value = 0;
            app.BandArea_2.Value = 0;
            app.BandArea_3.Value = 0;
            app.BandRelativeArea_1.Value = 0;
            app.BandRelativeArea_2.Value = 0;
            app.BandRelativeArea_3.Value = 0;

            % Reset number of bands
            app.NumberofBandsDropDown.Value = '1';

            % Reset box controls
            app.DeleteBoxButton.Enable = 0;
            control_strings = {'1'};
            app.BoxSelectionDropDown.Items = control_strings;
            app.BoxSelectionDropDown.Value = control_strings{1};

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath(genpath('../code/utilities'));
            movegui(app.GelBoxUIFigure,'center')


            colormap(app.GelBoxUIFigure, 'gray');
            app.fitting_options.shared_shape = true(1,1);
            app.fitting_options.shared_skewness = true(1,1);
            disableDefaultInteractivity(app.gel_image_axis)

        end

        % Menu selected function: LoadImageMenu
        function LoadImageButtonPushed(app, event)
            [file_string,path_string]=uigetfile2( ...
                {'*.png','PNG';'*.tif','TIF'}, ...
                'Select image file');
            if (path_string~=0)

                ResetDisplay(app)
                app.DataAnalysisMenu.Enable = 1;
                app.gel_data = [];
                app.gel_data.par_update = 0;
                app.d=[];


                app.gel_data.invert_status = 0;
                app.gel_data.image_file_string = fullfile(path_string,file_string);
                app.gel_data.im_data = imread(app.gel_data.image_file_string);
                if (ndims(app.gel_data.im_data)==3)
                    app.gel_data.im_data = rgb2gray(app.gel_data.im_data);
                end
                app.gel_data.original_image = app.gel_data.im_data;
                center_image_with_preserved_aspect_ratio( ...
                    app.gel_data.im_data, ...
                    app.gel_image_axis,[]);

                app.gel_data.imfinfo = imfinfo(app.gel_data.image_file_string);

            end

        end

        % Menu selected function: InvertImageMenu
        function InvertImageButtonPushed(app, event)
            app.gel_data.im_data = imcomplement(app.gel_data.im_data);
            app.gel_data.original_image = app.gel_data.im_data;
            center_image_with_preserved_aspect_ratio( ...
                app.gel_data.im_data, ...
                app.gel_image_axis,[]);
            app.gel_data.invert_status = 1;
        end

        % Menu selected function: LoadAnalysisMenu
        function LoadAnalysisButtonPushed(app, event)

            [file_string,path_string] = uigetfile2( ...
                {'*.gdf','Gel data file'},'Select file to load prior analysis');

            if (path_string~=0)
            % Delete any old boxes
            if (isfield(app.gel_data,'box_handle'))
                n = numel(app.gel_data.box_handle);
                for i=1:n
                    delete(app.gel_data.box_handle(i));
                end
            end
                app.DeleteBoxButton.Enable = 1;
                app.DataAnalysisMenu.Enable = 1;
                
                temp = load(fullfile(path_string,file_string),'-mat','save_data');
                save_data = temp.save_data;

                % Restore
                app.gel_data = [];
                app.d=[];
                app.gel_data.image_file_string = save_data.image_file_string;
                app.gel_data.im_data = save_data.im_data;
                app.gel_data.imfinfo = save_data.imfinfo;
                app.new_box = 0;


                center_image_with_preserved_aspect_ratio( ...
                    app.gel_data.im_data, ...
                    app.gel_image_axis,[]);
                if isfield(save_data,'par_fit')
                    band_no = numel(save_data.par_fit(1).band_no);
                elseif isfield(save_data,'par_est')
                    band_no = numel(save_data.par_est(1).band_no);
                else 
                    band_no = 1;
                end
                
                app.NumberofBandsDropDown.Value = num2str(band_no);
                
                                    
                % Check if this was a legacy gelbox gdf
                if isfield(save_data,'par_est')
                    ext_par_names = fieldnames(save_data.par_est);

                    if any(strcmp(ext_par_names,'shape_parameter'))

                        if isfield(save_data,'par_fit')
                            for i = 1 : length(save_data.par_fit)
                                save_data.par_fit(i).width_parameter = save_data.par_fit(i).shape_parameter;
                            end
                            save_data.par_fit = rmfield(save_data.par_fit,'shape_parameter');
                        end

                        if isfield(save_data,'par_est')
                            for i = 1 : length(save_data.par_est)
                                save_data.par_est(i).width_parameter = save_data.par_est(i).shape_parameter;
                                if band_no > 1
                                    save_data.par_est(i).width_parameter(2) = save_data.par_est(i).width_parameter(2) - save_data.par_est(i).width_parameter(1);
                                    save_data.par_est(i).skew_parameter(2) = save_data.par_est(i).skew_parameter(2) - save_data.par_est(i).skew_parameter(1);
                                end
                            end
                            save_data.par_est = rmfield(save_data.par_est,'shape_parameter');
                        end

                        if isfield(save_data,'par_con')
                            for i = 1 : length(save_data.par_con)
                                save_data.par_con(i).width_parameter = save_data.par_con(i).shape_parameter;
                            end
                            save_data.par_con = rmfield(save_data.par_con,'shape_parameter');
                        end
                    end
                end
                % Check if there were image adjustments
                
                if isfield(save_data, 'original_image')
                    app.gel_data.original_image = save_data.original_image;
                end
                
                if isfield(save_data, 'adjusted_image')
                    app.gel_data.adjusted_image = save_data.adjusted_image;
                end
                
                if isfield(save_data, 'image_adjustments')
                    app.gel_data.image_adjustments = save_data.image_adjustments;
                end
                
                n=size(save_data.box_position,1);
                names = {'band_no','peak_location','amplitude','width_parameter','skew_parameter'};
                for j = 1:n
                    for i = 1:numel(names)
                        for k = 1:band_no
                            if isfield(save_data,'par_fit')
                                app.gel_data.par_est(j).(names{i})(k) = save_data.par_fit(j).(names{i})(k);
                                app.loaded_analysis = 1;
                            else
                                app.gel_data.par_est(j).(names{i})(k) = 0;
                                app.gel_data.par_est(j).(names{i})(k) = [];
                                app.par_fit_na = 1;
                            end
                            
                            if isfield(save_data,'par_est') && ~isfield(save_data,'par_fit')
                                app.gel_data.par_est(j).(names{i})(k) = save_data.par_est(j).(names{i})(k);
                                app.loaded_analysis = 1;
                            end

                            if isfield(save_data,'par_con')
                                app.gel_data.par_con(j).(names{i})(k) = save_data.par_con(j).(names{i})(k);
                                app.loaded_analysis = 1;
                            else
                                app.gel_data.par_con(j).(names{i})(k) = false;
                            end
                        end
                    end
                end
                control_strings = [];
                app.gel_data.par_update = zeros(1,n);
                
                if isfield(save_data,'par_est') && app.gel_data.par_est(1).band_no(end) ~= 1
                    val = band_no;
                    switch val
                        case 1
                            try
                                app.BandRelativeAreaLabel_1.Value = 0;
                                app.BandRelativeAreaLabel_1.Enable = 0;
                                app.BandArea_2.Value = 0;
                                app.BandArea_2.Enable = 0;
                                app.BandRelativeArea_2.Value = 0;
                                app.BandRelativeArea_2.Enable = 0;
                                app.BandAreaLabel_2.Enable = 0;
                                app.BandRelativeAreaLabel_2.Enable = 0;
                                app.BandArea_3.Value = 0;
                                app.BandArea_3.Enable = 0;
                                app.BandRelativeArea_3.Value = 0;
                                app.BandRelativeArea_3.Enable = 0;
                                app.BandAreaLabel_3.Enable = 0;
                                app.BandRelativeAreaLabel_3.Enable = 0;
                            end
                        case 2
                            app.BandRelativeArea_1.Enable = 1;
                            app.BandRelativeAreaLabel_1.Enable = 1;
                            app.BandArea_2.Enable = 1;
                            app.BandRelativeArea_2.Enable = 1;
                            app.BandAreaLabel_2.Enable = 1;
                            app.BandRelativeAreaLabel_2.Enable = 1;
                            try
                                app.BandArea_3.Value = 0;
                                app.BandArea_3.Enable = 0;
                                app.BandRelativeArea_3.Enable = 0;
                                app.BandRelativeArea_3.Value = 0;
                                app.BandAreaLabel_3.Enable = 0;
                                app.BandRelativeAreaLabel_3.Enable = 0;
                            end

                        case 3
                            app.BandRelativeArea_1.Enable = 1;
                            app.BandRelativeAreaLabel_1.Enable = 1;
                            app.BandArea_2.Enable = 1;
                            app.BandRelativeArea_2.Enable = 1;
                            app.BandAreaLabel_2.Enable = 1;
                            app.BandRelativeAreaLabel_2.Enable = 1;
                            app.BandArea_3.Enable = 1;
                            app.BandRelativeArea_3.Enable = 1;
                            app.BandAreaLabel_3.Enable = 1;
                            app.BandRelativeAreaLabel_3.Enable = 1;
                    end
                end

                for i=1:n
                    app.gel_data.box_handle(i) = images.roi.Rectangle(app.gel_image_axis, ...
                        'Position',save_data.box_position(i,:));
                    control_strings{i} = sprintf('%.0f',i);
                    app.d.box(i).fitting_mode = band_no;
                end

                app.BoxSelectionDropDown.Items = control_strings;
                app.BoxSelectionDropDown.Value = control_strings{1};

                for i=1:n
                    app.gel_data.box_handle(i).FaceAlpha = 0;
                    if (i~=1)
                        app.gel_data.box_handle(i).Color = [1 0 0];
                        app.gel_data.box_handle(i).InteractionsAllowed = 'none';
                    else
                        app.gel_data.box_handle(i).Color = [0 1 0];
                        app.gel_data.box_handle(i).InteractionsAllowed = 'all';

                    end

                    p = app.gel_data.box_handle(i).Position;
                    app.gel_data.box_label(i) = text(p(1)+p(3),p(2)-50,sprintf('%.0f',i), ...
                        'Parent',app.gel_image_axis,'FontWeight',"bold","FontSize",18);

                    app.gel_data.old_width = p(3);
                    app.gel_data.old_height = p(4);

                    i=i;
                    addlistener(app.gel_data.box_handle(i),"MovingROI",@(src,evt) new_box_position2(evt));
                end

                % Need this to make labels
                drawnow;
            end

            UpdateDisplay(app)
%             app.loaded_analysis = 0;
            app.par_est_na = 0;

            % Nested function
            function new_box_position2(evt);
                if (isfield(app.gel_data,'box_position'))
                    box_position = app.gel_data.box_position;
                    [r,c]=size(box_position);
                    if (r>=n)&(~isequal(box_position(n,:),evt.CurrentPosition))
                        app.new_box = n;
                        old_size = box_position(n,3:4);
                        current_size = evt.CurrentPosition(3:4);
                        if isequal(old_size,current_size)
                            app.single_box_callback = 1;                            
                        end
                        app.moving_box = 1;
                        UpdateDisplay(app)
                        app.moving_box = 0;
                        app.single_box_callback = 0;
                    end
                else
                    app.new_box = n;
                    app.single_box_callback = 1;
                    app.moving_box = 1;
                    UpdateDisplay(app)
                    app.single_box_callback = 0;
                    app.moving_box = 0;
                end
            end
        end

        % Button pushed function: NewBoxButton
        function NewBoxButtonPushed(app, event)
            if (~isfield(app.gel_data,'box_handle'))
                app.DeleteBoxButton.Enable = 1;
                n=1;
                app.gel_data.box_handle(n) = drawrectangle(app.gel_image_axis);
                p = app.gel_data.box_handle(n).Position;
                app.gel_data.old_width = p(3);
                app.gel_data.old_height = p(4);
            else
                n = 1 + numel(app.gel_data.box_handle);
                p = app.gel_data.box_handle(n-1).Position;

                app.gel_data.box_handle(n) = images.roi.Rectangle(app.gel_image_axis, ...
                    'Position',p + [150,0,0,0]);
                for i=1:(n-1)
                    app.gel_data.box_handle(i).InteractionsAllowed = 'none';
                end
                
            end

            addlistener(app.gel_data.box_handle(n),"MovingROI", ...
                @(src,evt) new_box_position(evt));

            % Set color to last box
            app.gel_data.box_handle(n).Color = [0 1 0];
            app.gel_data.box_handle(n).FaceAlpha = 0;
            for i=1:(n-1)
                app.gel_data.box_handle(i).Color = [1 0 0];
            end

            % Add in a label
            p = app.gel_data.box_handle(n).Position;
            app.gel_data.box_label(n) = text(app.gel_image_axis, ...
                p(1)+p(3),p(2)-50,sprintf('%.0f',n),'FontWeight',"bold","FontSize",18);

            % Update zoom control
            for i=1:n
                control_strings{i}=sprintf('%.0f',i);
            end

            app.BoxSelectionDropDown.Items = control_strings;
            app.BoxSelectionDropDown.Value = control_strings{n};

            app.DeleteBoxButton.Enable = 1;
            app.SelectedBoxInformationMenu.Enable = 1;
            app.new_box = n;
            app.single_box_callback = 1;
            app.d.box(n).fitting_mode = str2num(app.NumberofBandsDropDown.Value);
            UpdateDisplay(app)
            app.single_box_callback = 0;

            function new_box_position(evt);
                if (isfield(app.gel_data,'box_position'))
                    box_position = app.gel_data.box_position;
                    [r,c]=size(box_position);
                    if (r>=n)&(~isequal(box_position(n,:),evt.CurrentPosition))
                        app.new_box = n;
                        old_size = box_position(n,3:4);
                        current_size = evt.CurrentPosition(3:4);
                        if isequal(old_size,current_size)
                            app.single_box_callback = 1;                            
                        end
                        app.moving_box = 1;
                        UpdateDisplay(app);
                        app.single_box_callback = 0;
                        app.moving_box = 0;

                    end
                else
                    app.new_box = n;
                    app.single_box_callback = 1;
                    app.moving_box = 1;
                    UpdateDisplay(app);
                    app.single_box_callback = 0;
                    app.moving_box = 0;

                end
            end

        end

        % Value changed function: NumberofBandsDropDown
        function NumberofBandsDropDownValueChanged(app, event)
            value = app.NumberofBandsDropDown.Value;
            app.mode_updated = 1;
            for u = 1:numel(app.gel_data.par_update)
            app.gel_data.par_update(u) = 0;
            end

            switch value
                case '1'
                    try
                        app.BandRelativeAreaLabel_1.Enable = 0;
                        app.BandArea_2.Value = 0;
                        app.BandArea_2.Enable = 0;
                        app.BandRelativeArea_2.Value = 0;
                        app.BandRelativeArea_2.Enable = 0;
                        app.BandAreaLabel_2.Enable = 0;
                        app.BandRelativeAreaLabel_2.Enable = 0;
                        app.BandArea_3.Value = 0;
                        app.BandArea_3.Enable = 0;
                        app.BandRelativeArea_3.Value = 0;
                        app.BandRelativeArea_3.Enable = 0;
                        app.BandAreaLabel_3.Enable = 0;
                        app.BandRelativeAreaLabel_3.Enable = 0;
                    end
                case '2'
                    app.BandRelativeArea_1.Enable = 1;
                    app.BandRelativeAreaLabel_1.Enable = 1;
                    app.BandArea_2.Enable = 1;
                    app.BandRelativeArea_2.Enable = 1;
                    app.BandAreaLabel_2.Enable = 1;
                    app.BandRelativeAreaLabel_2.Enable = 1;
                    try
                        app.BandArea_3.Value = 0;
                        app.BandArea_3.Enable = 0;
                        app.BandRelativeArea_3.Enable = 0;
                        app.BandRelativeArea_3.Value = 0;
                        app.BandAreaLabel_3.Enable = 0;
                        app.BandRelativeAreaLabel_3.Enable = 0;
                    end

                case '3'
                    app.BandRelativeArea_1.Enable = 1;
                    app.BandRelativeAreaLabel_1.Enable = 1;
                    app.BandArea_2.Enable = 1;
                    app.BandRelativeArea_2.Enable = 1;
                    app.BandAreaLabel_2.Enable = 1;
                    app.BandRelativeAreaLabel_2.Enable = 1;
                    app.BandArea_3.Enable = 1;
                    app.BandRelativeArea_3.Enable = 1;
                    app.BandAreaLabel_3.Enable = 1;
                    app.BandRelativeAreaLabel_3.Enable = 1;
            end
            selected_box = str2num(app.BoxSelectionDropDown.Value);
            app.d.box(selected_box).fitting_mode = str2num(app.NumberofBandsDropDown.Value);
            app.single_box_callback = 1;
            UpdateDisplay(app)
            app.single_box_callback = 0;
            app.mode_updated = 0;

        end

        % Menu selected function: SaveAnalysisMenu
        function SaveAnalysisButtonPushed(app, event)
            save_data.image_file_string = app.gel_data.image_file_string;
            try
                save_data.box_position = app.gel_data.box_position;
            catch
                warndlg('The analysis boxes are not available.')
                return
            end
            save_data.im_data = app.gel_data.im_data;
            save_data.imfinfo = app.gel_data.imfinfo;
            save_data.original_image = app.gel_data.original_image;
            if isfield(app.gel_data,'adjusted_image')
                save_data.adjusted_image = app.gel_data.adjusted_image;
            else
                save_data.adjusted_image = [];
            end

            if isfield(app.gel_data,'image_adjustments')
                save_data.image_adjustments = app.gel_data.image_adjustments;
            else
                save_data.image_adjustments = [];
            end
            
            save_data.fitting_mode = app.d.box.fitting_mode;
            
            number_of_boxes = size(save_data.box_position,1);
            names = {'band_no','peak_location','amplitude','width_parameter','skew_parameter'};
            for j = 1:number_of_boxes
                for i = 1:numel(names)
                    save_data.par_est(j).(names{i}) = app.gel_data.par_est(j).(names{i});
                    save_data.par_fit(j).(names{i}) = app.gel_data.par_fit(j).(names{i});
                    save_data.par_con(j).(names{i}) = app.gel_data.par_con(j).(names{i});
                end
            end

            [file_string,path_string] = uiputfile2( ...
                {'*.gdf','Gel data file'},'Select file to save analysis');

            if (path_string~=0)
                save(fullfile(path_string,file_string),'save_data');
            end
        end

        % Value changed function: BoxSelectionDropDown
        function BoxSelectionDropDownValueChanged(app, event)
            selected_box = str2num(app.BoxSelectionDropDown.Value);
            control_strings = app.BoxSelectionDropDown.Items;

            n = numel(control_strings);
            for i=1:n
                if (i~=selected_box)
                    app.gel_data.box_handle(i).Color = [1 0 0];
                    app.gel_data.box_handle(i).InteractionsAllowed = 'none';
                else
                    app.gel_data.box_handle(i).Color = [0 1 0];
                    app.gel_data.box_handle(i).InteractionsAllowed = 'all';
                end
            end
            
            value = app.d.box(selected_box).fitting_mode;
            app.NumberofBandsDropDown.Value = num2str(value);
            switch value
                case 1
                    try
                        app.BandRelativeArea_1.Value = 0;
                        app.BandRelativeAreaLabel_1.Enable = 0;
                        app.BandArea_2.Value = 0;
                        app.BandArea_2.Enable = 0;
                        app.BandRelativeArea_2.Value = 0;
                        app.BandRelativeArea_2.Enable = 0;
                        app.BandAreaLabel_2.Enable = 0;
                        app.BandRelativeAreaLabel_2.Enable = 0;
                        app.BandArea_3.Value = 0;
                        app.BandArea_3.Enable = 0;
                        app.BandRelativeArea_3.Value = 0;
                        app.BandRelativeArea_3.Enable = 0;
                        app.BandAreaLabel_3.Enable = 0;
                        app.BandRelativeAreaLabel_3.Enable = 0;
                    end
                case 2
                    app.BandRelativeArea_1.Enable = 1;
                    app.BandRelativeAreaLabel_1.Enable = 1;
                    app.BandArea_2.Enable = 1;
                    app.BandRelativeArea_2.Enable = 1;
                    app.BandAreaLabel_2.Enable = 1;
                    app.BandRelativeAreaLabel_2.Enable = 1;
                    try
                        app.BandArea_3.Value = 0;
                        app.BandArea_3.Enable = 0;
                        app.BandRelativeArea_3.Enable = 0;
                        app.BandRelativeArea_3.Value = 0;
                        app.BandAreaLabel_3.Enable = 0;
                        app.BandRelativeAreaLabel_3.Enable = 0;
                    end

                case 3
                    app.BandRelativeArea_1.Enable = 1;
                    app.BandRelativeAreaLabel_1.Enable = 1;
                    app.BandArea_2.Enable = 1;
                    app.BandRelativeArea_2.Enable = 1;
                    app.BandAreaLabel_2.Enable = 1;
                    app.BandRelativeAreaLabel_2.Enable = 1;
                    app.BandArea_3.Enable = 1;
                    app.BandRelativeArea_3.Enable = 1;
                    app.BandAreaLabel_3.Enable = 1;
                    app.BandRelativeAreaLabel_3.Enable = 1;
            end
            app.single_box_callback = 1;
            UpdateDisplay(app)
            app.single_box_callback = 0;
        end

        % Button pushed function: DeleteBoxButton
        function DeleteBoxButtonPushed(app, event)
            selected_box = str2num(app.BoxSelectionDropDown.Value);
            control_strings = app.BoxSelectionDropDown.Items;

            delete(app.gel_data.box_handle(selected_box))
            delete(app.gel_data.box_label(selected_box))
            field_names = fieldnames(app.gel_data.par_est);
            
            for u = 1 : numel(field_names)
                app.gel_data.par_est(selected_box).(field_names{u}) = [];
            end
            
            app.gel_data.box_handle(selected_box) = [];
            app.gel_data.box_label(selected_box) = [];
            app.gel_data.box_position(selected_box) = [];

            n = numel(control_strings) - 1;
            control_strings = {};
            for i=1:n
                control_strings{i} = sprintf('%.0f',i);
            end

            app.BoxSelectionDropDown.Items = control_strings;
            if selected_box == 1
                app.BoxSelectionDropDown.Value = num2str(selected_box);
            else
                app.BoxSelectionDropDown.Value = num2str(selected_box-1);
            end

            new_selected_box = str2num(app.BoxSelectionDropDown.Value);

            for i=1:n
                delete(app.gel_data.box_label(i));
                if (i~=new_selected_box)
                    app.gel_data.box_handle(i).Color = [1 0 0];
                    app.gel_data.box_handle(i).InteractionsAllowed = 'none';
                else
                    app.gel_data.box_handle(i).Color = [0 1 0];
                    app.gel_data.box_handle(i).InteractionsAllowed = 'all';
                end
            end

            app.gel_data.box_label = [];
            for i = 1:n
                p = app.gel_data.box_handle(i).Position;
                app.gel_data.box_label(i) = text(p(1)+p(3),p(2)-50,sprintf('%.0f',i), ...
                    'Parent',app.gel_image_axis,'FontWeight',"bold",'FontSize',18);

                app.gel_data.old_width = p(3);
                app.gel_data.old_height = p(4);
            end
            
            app.single_box_callback = 1;
            UpdateDisplay(app)
            app.single_box_callback = 0;
            
        end

        % Menu selected function: ExportResultsMenu
        function OutputButtonPushed(app, event)
            o = [];
            o.image_file{1} = app.gel_data.image_file_string;
            n = numel(app.gel_data.box_handle);
            for i = 2 : n
            o.image_file{i,1} = '';
            end
            for i=1:n
                o.box(i) = i;
                o.total_area(i) = app.gel_data.box_data(i).total_area;
                o.background_area(i) = app.gel_data.box_data(i).background_area;
                num_of_bands = numel(app.gel_data.box_data(i).band_area);
                switch num_of_bands
                    case 1
                        o.band_area_bottom(i) = app.gel_data.summary(i).bottom;
                        o.band_area_middle(i) = NaN;
                        o.band_area_top(i) = NaN;
                    case 2
                        o.band_area_bottom(i) = app.gel_data.summary(i).bottom;
                        o.band_area_middle(i) = NaN;
                        o.band_area_top(i) = app.gel_data.summary(i).top;
                    case 3
                        o.band_area_bottom(i) = app.gel_data.summary(i).bottom;
                        o.band_area_middle(i) = app.gel_data.summary(i).middle;
                        o.band_area_top(i) = app.gel_data.summary(i).top;
                end

                o.band_left(i) = app.gel_data.box_data(i).position(1);
                o.band_top(i) = app.gel_data.box_data(i).position(2);
                o.band_width(i) = app.gel_data.box_data(i).position(3);
                o.band_height(i) = app.gel_data.box_data(i).position(4);
                o.fitting_mode{i} = app.gel_data.box_data(i).fitting_mode;
                o.num_of_bands(i) = num_of_bands;
                o.r_squared(i) = app.gel_data.summary(i).r_squared;
            end

            [file_string,path_string] = uiputfile2( ...
                {'*.xlsx','Excel file'},'Select file for results');
            
            d_out = o;
            names = fieldnames(d_out);
            
            for j = 1 : length(d_out)
                for i = 1 : numel(names)

                    [row,col] = size(d_out(j).(names{i}));

                    if row == 1 && col ~= 1
                        d_out(j).(names{i}) = (d_out(j).(names{i}))';
                    end
                end
                
            end
            
            output_file = fullfile(path_string,file_string);
            
            try
                delete(output_file);
            end
            
            writetable(struct2table(d_out),output_file,'Sheet','Summary')
             
            summary = app.gel_data.summary;

            for i = 1:n
                i_name = sprintf('box_%i',i);
            end

            switch num_of_bands
                case 1
                    summary = rmfield(summary,'bottom');
                    try
                    summary = rmfield(summary,'middle');
                    summary = rmfield(summary,'top');
                    end
                case 2
                    summary = rmfield(summary,'bottom');
                    summary = rmfield(summary,'top');
                    try
                    summary = rmfield(summary,'middle');
                    end

                case 3
                    summary = rmfield(summary,'bottom');
                    summary = rmfield(summary,'middle');
                    summary = rmfield(summary,'top');

            end
            summary = rmfield(summary,'r_squared');
            summary = rmfield(summary,'inset');
            summary = rmfield(summary,'summary_inset');
            summary = rmfield(summary,'box_position');

            names = fieldnames(summary);
            for j = 1 : length(summary)
                for i = 1 : numel(names)

                    [row,col] = size(summary(j).(names{i}));

                    if row == 1 && col ~= 1
                        summary(j).(names{i}) = (summary(j).(names{i}))';
                    end
                end
                sheet = sprintf('box_%i',j);
                writetable(struct2table(summary(j)),output_file,'Sheet',sheet)
            end


        end

        % Menu selected function: GelImageFileInformationMenu
        function GelImageFileInformationMenuSelected(app, event)
            app.ImageFileTextDialog = ImFInfoDialog(app);
        end

        % Close request function: GelBoxUIFigure
        function GelBoxUIFigureCloseRequest(app, event)
            delete(app.ImageFileTextDialog)
            delete(app.SelectedBoxTextDialog)
            delete(app.FittingOptions)
            delete(app)

        end

        % Menu selected function: SelectedBoxInformationMenu
        function SelectedBoxInformationMenuSelected(app, event)
            app.SelectedBoxTextDialog = SelectedBoxDialog(app);
        end

        % Button pushed function: FittingParametersButton
        function FittingParametersButtonPushed(app, event)
            app.FittingOptions = FittingOptionsDialog(app);

        end

        % Menu selected function: SummaryPlotMenu
        function AnalysisSummaryPlotMenuSelected(app, event)
            app.SummaryPlot = SummaryPlotWindow(app);
        end

        % Value changed function: DrawFittingCheckBox
        function DrawFittingCheckBoxValueChanged(app, event)
            value = app.DrawFittingCheckBox.Value;
            if value
            app.single_box_callback = 1;
            UpdateDisplay(app)
            app.single_box_callback = 0;
            end
        end

        % Button pushed function: AdjustImageButton
        function AdjustImageButtonPushed(app, event)
            app.AdjustImage = AdjustImageWindow(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GelBoxUIFigure = uifigure('Visible', 'off');
            app.GelBoxUIFigure.Name = 'GelBox';
            app.GelBoxUIFigure.CloseRequestFcn = createCallbackFcn(app, @GelBoxUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.GelBoxUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImageMenu = uimenu(app.FileMenu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.InvertImageMenu = uimenu(app.FileMenu);
            app.InvertImageMenu.MenuSelectedFcn = createCallbackFcn(app, @InvertImageButtonPushed, true);
            app.InvertImageMenu.Separator = 'on';
            app.InvertImageMenu.Text = 'Invert Image';
            app.LoadAnalysisMenu = uimenu(app.FileMenu);
            app.LoadAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadAnalysisButtonPushed, true);
            app.LoadAnalysisMenu.Separator = 'on';
            app.LoadAnalysisMenu.Text = 'Load Analysis';
            app.SaveAnalysisMenu = uimenu(app.FileMenu);
            app.SaveAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisMenu.Separator = 'on';
            app.SaveAnalysisMenu.Text = 'Save Analysis';
            app.ExportResultsMenu = uimenu(app.FileMenu);
            app.ExportResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.ExportResultsMenu.Separator = 'on';
            app.ExportResultsMenu.Text = 'Export Results';
            app.DataAnalysisMenu = uimenu(app.GelBoxUIFigure);
            app.DataAnalysisMenu.Enable = 'off';
            app.DataAnalysisMenu.Text = 'Data Analysis';
            app.GelImageFileInformationMenu = uimenu(app.DataAnalysisMenu);
            app.GelImageFileInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @GelImageFileInformationMenuSelected, true);
            app.GelImageFileInformationMenu.Text = 'Gel Image File Information';
            app.SelectedBoxInformationMenu = uimenu(app.DataAnalysisMenu);
            app.SelectedBoxInformationMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectedBoxInformationMenuSelected, true);
            app.SelectedBoxInformationMenu.Separator = 'on';
            app.SelectedBoxInformationMenu.Text = 'Selected Box Information';
            app.SummaryPlotMenu = uimenu(app.DataAnalysisMenu);
            app.SummaryPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisSummaryPlotMenuSelected, true);
            app.SummaryPlotMenu.Separator = 'on';
            app.SummaryPlotMenu.Text = 'Summary Plot';
            app.OpticalDensitiesPanel = uipanel(app.GelBoxUIFigure);
            app.OpticalDensitiesPanel.Title = 'Optical Densities';
            app.raw_density = uiaxes(app.OpticalDensitiesPanel);
            xlabel(app.raw_density, 'Optical Density (A.U.)')
            ylabel(app.raw_density, 'Pixel')
            app.background_corrected_raw_density = uiaxes(app.OpticalDensitiesPanel);
            xlabel(app.background_corrected_raw_density, 'Optical Density (A.U.)')
            ylabel(app.background_corrected_raw_density, 'Pixel')
            app.box_inset = uiaxes(app.OpticalDensitiesPanel);
            xlabel(app.box_inset, 'Optical Density')
            ylabel(app.box_inset, 'Pixel')
            app.BoxZoomLabel = uilabel(app.OpticalDensitiesPanel);
            app.BoxZoomLabel.Text = 'Box Zoom';
            app.RawOpticalDensityLabel_2 = uilabel(app.OpticalDensitiesPanel);
            app.RawOpticalDensityLabel_2.Text = 'Raw Optical Density';
            app.BackgroundCorrectedOpticalDensityLabel_2 = uilabel(app.OpticalDensitiesPanel);
            app.BackgroundCorrectedOpticalDensityLabel_2.Text = 'Background Corrected Optical Density';
            app.TotalAreaEditFieldLabel = uilabel(app.OpticalDensitiesPanel);
            app.TotalAreaEditFieldLabel.Text = 'Total Area';
            app.TotalAreaField = uieditfield(app.OpticalDensitiesPanel, 'numeric');
            app.TotalAreaField.ValueDisplayFormat = '%.2f';
            app.TotalAreaField.Editable = 'off';
            app.BackgroundAreaLabel = uilabel(app.OpticalDensitiesPanel);
            app.BackgroundAreaLabel.Text = 'Background Area';
            app.BackgroundAreaField = uieditfield(app.OpticalDensitiesPanel, 'numeric');
            app.BackgroundAreaField.ValueDisplayFormat = '%.2f';
            app.BackgroundAreaField.Editable = 'off';
            app.BackgroundCorrAreaLabel = uilabel(app.OpticalDensitiesPanel);
            app.BackgroundCorrAreaLabel.Text = 'Background Corr. Area';
            app.BackgroundCorrAreaField = uieditfield(app.OpticalDensitiesPanel, 'numeric');
            app.BackgroundCorrAreaField.ValueDisplayFormat = '%.2f';
            app.BackgroundCorrAreaField.Editable = 'off';
            app.GelImagePanel = uipanel(app.GelBoxUIFigure);
            app.GelImagePanel.Title = 'Gel Image';
            app.gel_image_axis = uiaxes(app.GelImagePanel);
            app.gel_image_axis.XTick = [];
            app.gel_image_axis.YTick = [];
            app.AdjustImageButton = uibutton(app.GelImagePanel, 'push');
            app.AdjustImageButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustImageButtonPushed, true);
            app.AdjustImageButton.Text = 'Adjust Image';
            app.NewBoxButton = uibutton(app.GelImagePanel, 'push');
            app.NewBoxButton.ButtonPushedFcn = createCallbackFcn(app, @NewBoxButtonPushed, true);
            app.NewBoxButton.Text = 'New Box';
            app.DeleteBoxButton = uibutton(app.GelImagePanel, 'push');
            app.DeleteBoxButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBoxButtonPushed, true);
            app.DeleteBoxButton.Enable = 'off';
            app.DeleteBoxButton.Text = 'Delete Box';
            app.BoxSelectionDropDownLabel = uilabel(app.GelImagePanel);
            app.BoxSelectionDropDownLabel.Text = 'Box Selection';
            app.BoxSelectionDropDown = uidropdown(app.GelImagePanel);
            app.BoxSelectionDropDown.Items = {};
            app.BoxSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @BoxSelectionDropDownValueChanged, true);
            app.BoxSelectionDropDown.Placeholder = 'No data';
            app.BoxSelectionDropDown.Value = {};
            app.FittingPanel = uipanel(app.GelBoxUIFigure);
            app.FittingPanel.Title = 'Fitting';
            app.background_corrected_raw_density_fit = uiaxes(app.FittingPanel);
            xlabel(app.background_corrected_raw_density_fit, 'Optical Density (A.U.)')
            ylabel(app.background_corrected_raw_density_fit, 'Pixel')
            app.raw_density_fit = uiaxes(app.FittingPanel);
            xlabel(app.raw_density_fit, 'Optical Density (A.U.)')
            ylabel(app.raw_density_fit, 'Pixel')
            app.RawOpticalDensityLabel = uilabel(app.FittingPanel);
            app.RawOpticalDensityLabel.Text = 'Raw Optical Density';
            app.BackgroundCorrectedOpticalDensityLabel = uilabel(app.FittingPanel);
            app.BackgroundCorrectedOpticalDensityLabel.Text = 'Background Corrected Optical Density';
            app.DrawFittingCheckBox = uicheckbox(app.FittingPanel);
            app.DrawFittingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawFittingCheckBoxValueChanged, true);
            app.DrawFittingCheckBox.Text = 'Draw Fitting';
            app.RsquaredLabel = uilabel(app.FittingPanel);
            app.RsquaredLabel.Text = 'R - squared';
            app.rsquaredField = uieditfield(app.FittingPanel, 'numeric');
            app.rsquaredField.ValueDisplayFormat = '%.3f';
            app.rsquaredField.Editable = 'off';
            app.BandAreaLabel_1 = uilabel(app.FittingPanel);
            app.BandAreaLabel_1.Text = 'Band Area 1 (Red)';
            app.BandArea_1 = uieditfield(app.FittingPanel, 'numeric');
            app.BandArea_1.ValueDisplayFormat = '%.2f';
            app.BandArea_1.Editable = 'off';
            app.BandRelativeAreaLabel_1 = uilabel(app.FittingPanel);
            app.BandRelativeAreaLabel_1.Enable = 'off';
            app.BandRelativeAreaLabel_1.Text = 'Band Relative Area 1 (Red)';
            app.BandRelativeArea_1 = uieditfield(app.FittingPanel, 'numeric');
            app.BandRelativeArea_1.ValueDisplayFormat = '%.2f';
            app.BandRelativeArea_1.Editable = 'off';
            app.BandRelativeArea_1.Enable = 'off';
            app.BandAreaLabel_2 = uilabel(app.FittingPanel);
            app.BandAreaLabel_2.Enable = 'off';
            app.BandAreaLabel_2.Text = 'Band Area 2 (Blue)';
            app.BandArea_2 = uieditfield(app.FittingPanel, 'numeric');
            app.BandArea_2.ValueDisplayFormat = '%.2f';
            app.BandArea_2.Editable = 'off';
            app.BandArea_2.Enable = 'off';
            app.BandRelativeAreaLabel_2 = uilabel(app.FittingPanel);
            app.BandRelativeAreaLabel_2.Enable = 'off';
            app.BandRelativeAreaLabel_2.Text = 'Band Relative Area 2 (Blue)';
            app.BandRelativeArea_2 = uieditfield(app.FittingPanel, 'numeric');
            app.BandRelativeArea_2.ValueDisplayFormat = '%.2f';
            app.BandRelativeArea_2.Editable = 'off';
            app.BandRelativeArea_2.Enable = 'off';
            app.BandAreaLabel_3 = uilabel(app.FittingPanel);
            app.BandAreaLabel_3.Enable = 'off';
            app.BandAreaLabel_3.Text = 'Band Area 3 (Yellow)';
            app.BandArea_3 = uieditfield(app.FittingPanel, 'numeric');
            app.BandArea_3.ValueDisplayFormat = '%.2f';
            app.BandArea_3.Editable = 'off';
            app.BandArea_3.Enable = 'off';
            app.BandRelativeAreaLabel_3 = uilabel(app.FittingPanel);
            app.BandRelativeAreaLabel_3.Enable = 'off';
            app.BandRelativeAreaLabel_3.Text = 'Band Relative Area 3 (Yellow)';
            app.BandRelativeArea_3 = uieditfield(app.FittingPanel, 'numeric');
            app.BandRelativeArea_3.ValueDisplayFormat = '%.2f';
            app.BandRelativeArea_3.Editable = 'off';
            app.BandRelativeArea_3.Enable = 'off';
            app.NumberofBandsDropDownLabel = uilabel(app.FittingPanel);
            app.NumberofBandsDropDownLabel.Text = 'Number of Bands';
            app.NumberofBandsDropDown = uidropdown(app.FittingPanel);
            app.NumberofBandsDropDown.Items = {'1', '2', '3'};
            app.NumberofBandsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofBandsDropDownValueChanged, true);
            app.NumberofBandsDropDown.Value = '1';
            app.FittingParametersButton = uibutton(app.FittingPanel, 'push');
            app.FittingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @FittingParametersButtonPushed, true);
            app.FittingParametersButton.Text = 'Fitting Parameters';
            % Show the figure after all components are created
            app.GelBoxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GelBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GelBoxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GelBoxUIFigure)
        end
    end
end