classdef SummaryPlotWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SummaryPlotUIFigure           matlab.ui.Figure
        FigureFileNameField           matlab.ui.control.EditField
        FigureFileNameEditFieldLabel  matlab.ui.control.Label
        GenerateSummaryPlotsButton    matlab.ui.control.Button
        OutputPathField               matlab.ui.control.EditField
        SelectOutputFolderButton      matlab.ui.control.Button
        ThirdBand                     matlab.ui.control.EditField
        ThirdBandLabel                matlab.ui.control.Label
        SecondBand                    matlab.ui.control.EditField
        SecondBandLabel               matlab.ui.control.Label
        FirstBand                     matlab.ui.control.EditField
        FirstBandLabel                matlab.ui.control.Label
        NumberofbandsDropDown         matlab.ui.control.DropDown
        NumberofbandsDropDownLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        GelBoxApp % Description
    end

    properties (Access = public)
        output_path % Description
        box_layout_fname
        f_title
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)

            movegui(app.SummaryPlotUIFigure,'center')


            app.GelBoxApp = caller;


        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            app.output_path = uigetdir2;
            if (app.output_path~=0)
            app.OutputPathField.Value = app.output_path;
            end
        end

        % Value changed function: FigureFileNameField
        function FigureFileNameFieldValueChanged(app, event)
            fname = app.FigureFileNameField.Value;
            app.f_title = fname;
            app.box_layout_fname = sprintf('%s\\%s_box_layout', ...
                app.output_path,fname);
            app.NumberofbandsDropDownLabel.Enable = 1;
            app.NumberofbandsDropDown.Enable = 1;
            app.FirstBandLabel.Enable = 1;
            app.FirstBand.Enable = 1;

        end

        % Value changed function: NumberofbandsDropDown
        function NumberofbandsDropDownValueChanged(app, event)
            value = app.NumberofbandsDropDown.Value;
            switch value
                case "1"
                    app.SecondBand.Enable = 0;
                    app.SecondBandLabel.Enable = 0;
                    app.ThirdBand.Enable = 0;
                    app.ThirdBandLabel.Enable = 0;
                case "2"
                    app.SecondBand.Enable = 1;
                    app.SecondBandLabel.Enable = 1;
                    app.ThirdBand.Enable = 0;
                    app.ThirdBandLabel.Enable = 0;
                case "3"
                    app.SecondBand.Enable = 1;
                    app.SecondBandLabel.Enable = 1;
                    app.ThirdBand.Enable = 1;
                    app.ThirdBandLabel.Enable = 1;
            end
        end

        % Button pushed function: GenerateSummaryPlotsButton
        function GenerateSummaryPlotsButtonPushed(app, event)

            max_x = [];
            max_x_fit = [];
            scaling = {'same_scale','auto_scale'};
            summary = app.GelBoxApp.gel_data.summary;
            [m,n] = size(summary);
            for i = 1 : n
                max_x(i) = max(summary(i).x);
                max_x_fit(i) = max(summary(i).x_fit);
                min_x_fit(i) = min(summary(i).x_fit);
            end

            max_x = ceil(max(max_x)/100)*100;
            max_x_fit = ceil(max(max_x_fit)/100)*100;
            min_x_fit = ceil(min(min_x_fit)/-100)*-100;


            box_positions = [];

            for i = 1 : length(summary)

                box_positions(i,:) = summary(i).box_position;

            end


            im_a = app.GelBoxApp.gel_data.im_data;
            im_or = app.GelBoxApp.gel_data.original_image;


            if n == 1
                no_of_panels_wide = 3;
            else
                no_of_panels_wide = 6;
            end

            total_figures = n*3;
            fig_handles = 1:total_figures;
            no_of_panels_high = 2 + (total_figures + ...
                mod(total_figures,no_of_panels_wide))/no_of_panels_wide;

            left_pads = 0.05 * ones(1,no_of_panels_wide);
            left_pads([1]) = 0.4;
            left_pads([4]) = 0.7;
            right_pads = 0.25 * ones(1,no_of_panels_wide);

            if no_of_panels_wide == 3
                right_pads(no_of_panels_wide) = 0.7;
            else
                right_pads(no_of_panels_wide) = 0.7;
            end

            omit_panels = [2:no_of_panels_wide];
            omit_panels = [omit_panels, no_of_panels_wide+2:2*no_of_panels_wide];

            if mod(total_figures,no_of_panels_wide) ~= 0
                res_total_figures = no_of_panels_high * no_of_panels_wide;
                res_total_figures = 1 : res_total_figures;
                omit_panels = [omit_panels ...
                    res_total_figures(end-2) ...
                    res_total_figures(end-1) ...
                    res_total_figures(end)];
            else
                omit_panels = omit_panels;
            end

            im_handles = 2*no_of_panels_wide + 1:3:total_figures + 2*no_of_panels_wide;
            back_fit_handles = 2*no_of_panels_wide + 2:3:total_figures + 2*no_of_panels_wide;
            fit_handles = 2*no_of_panels_wide + 3:3:total_figures + 2*no_of_panels_wide;

            right_subplot_adjustments = zeros(1, no_of_panels_wide*no_of_panels_high);
            right_subplot_adjustments(1) = -no_of_panels_wide + 0.005;
            right_subplot_adjustments(1+no_of_panels_wide) = -no_of_panels_wide + 0.005;
            relative_row_heights = ones(1,no_of_panels_high);
            relative_row_heights(1) = 3;
            relative_row_heights(2) = 1;
            
            for fig_no = 1 : 2
                colormap('gray')
                sp = initialise_publication_quality_figure( ...
                    'no_of_panels_wide', no_of_panels_wide, ...
                    'no_of_panels_high', no_of_panels_high, ...
                    'top_margin', 0.5, ...
                    'bottom_margin', 0.15, ...
                    'right_margin', 0.15, ...
                    'individual_padding', 1, ...
                    'left_pads', repmat(left_pads,[1 no_of_panels_high]), ...
                    'right_pads', repmat(right_pads,[1 no_of_panels_high]), ...
                    'axes_padding_top', 0.1, ...
                    'axes_padding_bottom',0.35, ...
                    'panel_label_font_size', 0, ...
                    'omit_panels', omit_panels, ...
                    'right_subplot_adjustments', right_subplot_adjustments,...
                    'relative_row_heights', relative_row_heights,...
                    'figure_handle',1);
                
                colors = {'r','b','y'};
                labels = {app.FirstBand.Value, ...
                    app.SecondBand.Value, ...
                    app.ThirdBand.Value};
                
                subplot(sp(1))
                title('Original Image','FontSize',11)
                hold on
                center_image_with_preserved_aspect_ratio( ...
                    im_or, ...
                    sp(1),[])
                [m,n] = size(im_or);
                rectangle('Position',[1 1 n-1 m-1 ],'EdgeColor','k','LineWidth',1)
                
                subplot(sp(7))
                title('Adjusted Image','FontSize',11)
                hold on
                center_image_with_preserved_aspect_ratio( ...
                    im_a, ...
                    sp(7),[])

                for i = 1 : length(summary)
                    rectangle('Position',box_positions(i,:),...
                        'EdgeColor','g',...
                        'LineWidth',2)
                    text(box_positions(i,1)+box_positions(i,3)+17,box_positions(i,2), ...
                        sprintf('%.0f',i),'FontWeight',"bold","FontSize",12);
                end

                [m,n] = size(im_a);
                rectangle('Position',[1 1 n-1 m-1 ],'EdgeColor','k','LineWidth',1)


                for i = 1 : length (im_handles)
                    h = subplot(sp(im_handles(i)));
                    colormap(h,"gray");
                    t1 = sprintf('Box %i',i);
                    title(t1, 'FontSize',7);
                    center_image_with_preserved_aspect_ratio( ...
                        summary(i).summary_inset,sp(im_handles(i)),[]);
                    x = size(summary(i).summary_inset,2) - size(summary(i).inset,2);
                    x = 0.5*x + 1;
                    y = size(summary(i).summary_inset,1) - size(summary(i).inset,1);
                    y = 0.5*y + 1;
                    rectangle('Position',[11 11 size(summary(i).inset,2) size(summary(i).inset,1)],'EdgeColor','g')
                    subplot(sp(back_fit_handles(i)))
                    plot(summary(i).x,summary(i).y,'k', 'LineWidth',1.5);

                    hold on

                    subplot(sp(fit_handles(i)))
                    plot(summary(i).x-(summary(i).x_back)', ...
                        summary(i).y,'-.k', 'LineWidth',1.5);
                    hold on

                    switch app.NumberofbandsDropDown.Value
                        case "1"
                            subplot(sp(fit_handles(i)))
                            bottom = patch(summary(i).band_1, ...
                                summary(i).y,colors{1},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{1},'EdgeAlpha',0.05);
                            tot_area = summary(i).bottom;
                            bottom_area = (summary(i).bottom)/tot_area;

                            subplot(sp(back_fit_handles(i)))
                            hold on
                            patch(summary(i).x_back+summary(i).band_1, ...
                                summary(i).y,colors{1},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{1},'EdgeAlpha',0.05);

                            str_bottom = sprintf('%s = %.2f', ...
                                string(labels{1}),bottom_area);

                            leg_labels = {str_bottom};
                            figs = [bottom];

                            legendflex([bottom], leg_labels, ...
                                'xscale',0.15, ...
                                'anchor',{'ne','ne'}, ...
                                'buffer',[60 0], ...
                                'padding',[1 1 2], ...
                                'FontSize',6, ...
                                'text_y_padding', -2);

                        case "2"
                            subplot(sp(fit_handles(i)))
                            % xlim([0 20000])
                            bottom = patch(summary(i).band_1, ...
                                summary(i).y,colors{1},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{1},'EdgeAlpha',0.05);
                            top = patch(summary(i).band_2, ...
                                summary(i).y,colors{2},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{2},'EdgeAlpha',0.05);
                            tot_area = summary(i).bottom + summary(i).top;
                            bottom_area = (summary(i).bottom)/tot_area;
                            top_area = (summary(i).top)/tot_area;

                            subplot(sp(back_fit_handles(i)))
                            hold on
                            patch(summary(i).x_back+summary(i).band_1, ...
                                summary(i).y,colors{1},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{1},'EdgeAlpha',0.05);
                            patch(summary(i).x_back+summary(i).band_2, ...
                                summary(i).y,colors{2},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{2},'EdgeAlpha',0.05);
                            str_bottom = sprintf('%s = %.2f', ...
                                string(labels{1}),bottom_area);
                            str_top = sprintf('%s = %.2f', ...
                                string(labels{2}),top_area);

                            leg_labels = {str_bottom,str_top};
                            figs = [bottom top];

                        case "3"
                            subplot(sp(fit_handles(i)))
                            bottom = patch(summary(i).band_1, ...
                                summary(i).y,colors{1},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{1},'EdgeAlpha',0.05);
                            middle = patch(summary(i).band_2, ...
                                summary(i).y,colors{2},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{2},'EdgeAlpha',0.05);
                            top = patch(summary(i).band_3, ...
                                summary(i).y,colors{3},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{3},'EdgeAlpha',0.05);
                            tot_area = summary(i).bottom + summary(i).middle + summary(i).top;
                            bottom_area = (summary(i).bottom)/tot_area;
                            middle_area = (summary(i).middle)/tot_area;
                            top_area = (summary(i).top)/tot_area;

                            subplot(sp(back_fit_handles(i)))
                            hold on
                            patch(summary(i).x_back+summary(i).band_1, ...
                                summary(i).y,colors{1},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{1},'EdgeAlpha',0.05);
                            patch(summary(i).x_back+summary(i).band_2, ...
                                summary(i).y,colors{2},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{2},'EdgeAlpha',0.05);
                            patch(summary(i).x_back+summary(i).band_3, ...
                                summary(i).y,colors{3},'FaceAlpha',0.25, ...
                                'EdgeColor',colors{3},'EdgeAlpha',0.05);

                            str_bottom = sprintf('%s = %.2f', ...
                                string(labels{1}),bottom_area);
                            str_middle = sprintf('%s = %.2f', ...
                                string(labels{2}),middle_area);
                            str_top = sprintf('%s = %.2f', ...
                                string(labels{3}),top_area);

                            leg_labels = {str_bottom,str_middle,str_top};
                            figs = [bottom middle top];

                            legendflex([bottom middle top], leg_labels, ...
                                'xscale',0.15, ...
                                'anchor',{'ne','ne'}, ...
                                'buffer',[60 0], ...
                                'padding',[1 1 2], ...
                                'FontSize',6, ...
                                'text_y_padding', -2);
                    end


                    f_leg = sprintf('R^2 = %.3f',summary(i).r_squared);
                    f_color = '#1aff00';

                    subplot(sp(back_fit_handles(i)))
                    hold on
                    plot(summary(i).x_back,summary(i).y, ...
                        '-.m','LineWidth',1.5);
                    plot(summary(i).x_back + summary(i).x_fit, ...
                        summary(i).y,':',"LineWidth",1.5,'Color',f_color)

                    subplot(sp(fit_handles(i)))
                    hold on
                    plot(zeros(1,numel(summary(i).y)),summary(i).y, ...
                        '-.m','LineWidth',1.5);
                    f = plot(summary(i).x_fit, ...
                        summary(i).y,':',"LineWidth",1.5,'Color',f_color);
                    leg_labels{end+1} = f_leg;
                    figs(end+1) = f;

                    legendflex(figs, leg_labels, ...
                        'xscale',0.15, ...
                        'anchor',{'ne','ne'}, ...
                        'buffer',[60 0], ...
                        'padding',[1 1 2], ...
                        'FontSize',6, ...
                        'text_y_padding', 0);
                end
%                 sgtitle(app.f_title,'FontSize',15);
                
                for i = 1 : length(im_handles)
                    improve_axes('axis_handle',sp(im_handles(i)),...
                        'y_axis_label','Pixels', ...
                        'y_tick_decimal_places',0,...
                        'y_label_offset',-0.15,...
                        'x_tick_decimal_places',0, ...
                        'y_tick_decimal_places',0, ...
                        'x_axis_label','Pixels', ...
                        'x_label_offset',-0.35,...
                        'x_tick_labels',{'1',string(size(summary(i).inset,2))},...
                        'x_tick_label_positions',[11 10+size(summary(i).inset,2)],...
                        'y_axis_offset',-0.05,'y_ticks',[size(summary(i).summary_inset,1) 1],...
                        'y_tick_labels',{'1',string(size(summary(i).inset,1))},...
                        'y_tick_label_positions',[size(summary(i).inset,1)+10 11],...
                        'clip_x_axis',1,...
                        'clip_y_axis',1,...
                        'tick_font_size',8,...
                        'label_font_size',8,...
                        'y_label_rotation',90)
                end

                for i = 1:length(fit_handles)

                    if fig_no == 1
                        x_ticks = [min_x_fit max_x_fit];
                    else
                        x_pow = ceil(log10(max(summary(i).x-summary(i).x_back')));
                        x_tick_rounder = 10^(x_pow - 1);
                        x_t_end = ceil(max(summary(i).x-summary(i).x_back') ...
                            /x_tick_rounder)*x_tick_rounder;
                        if min(summary(i).x-summary(i).x_back') < 0
                            x_tick_rounder = -10^(x_pow - 2)*0.25;
                        else
                            x_tick_rounder = 10^(x_pow - 2)*0.25;
                        end
                        x_t_beginning = ceil(min(summary(i).x-summary(i).x_back')/x_tick_rounder)*x_tick_rounder;
                        x_ticks = [x_t_beginning x_t_end];
                    end


                    improve_axes('axis_handle',sp(fit_handles(i)),...
                        'x_tick_decimal_places',0, ...
                        'y_tick_decimal_places',0,...
                        'y_axis_label',{'Pixx_ticks = [min_x_fit max_x_fit];x_ticks = [min_x_fit max_x_fit];els'},'y_label_offset',-0.4,...
                        'x_label_offset',-0.30,...
                        'tick_font_size',8,...
                        'label_font_size',8,...
                        'x_axis_label',{'Opt.','Density (A.U.)'},...
                        'x_ticks', x_ticks,...
                        'y_ticks',[1 length(summary(i).x)],...
                        'title_font_size', 8, ...
                        'title_y_offset',1.2,...
                        'y_axis_off',1)
                end

                for i = 1:length(back_fit_handles)

                    if fig_no == 1
                        x_ticks = [0 max_x];
                    else
                        x_pow = ceil(log10(max(summary(i).x)));
                        x_tick_rounder = 10^(x_pow - 1);
                        x_t_end = ceil(max(summary(i).x)/x_tick_rounder)*x_tick_rounder;
                        x_ticks = [0 x_t_end];
                    end


                    improve_axes('axis_handle',sp(back_fit_handles(i)),...
                        'x_tick_decimal_places',0, ...
                        'y_tick_decimal_places',0,...
                        'y_axis_off',0,...
                        'x_label_offset',-0.30,...
                        'y_label_offset',-0.15,...
                        'tick_font_size',8,...
                        'label_font_size',8,...
                        'y_axis_label',{'Pixels'},...
                        'x_axis_label',{'Opt.','Density'},...
                        'x_ticks',x_ticks,...
                        'y_ticks',[1 length(summary(i).x)],...
                        'y_label_rotation',90)
                end
                fname = sprintf('%s_%s',app.box_layout_fname,scaling{fig_no});
                figure_export('output_file_string', fname, ...
                    'output_type', 'png');
            end
            delete(app)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SummaryPlotUIFigure = uifigure('Visible', 'off');
            app.SummaryPlotUIFigure.Name = 'Summary Plot';
            app.NumberofbandsDropDownLabel = uilabel(app.SummaryPlotUIFigure);
            app.NumberofbandsDropDownLabel.Enable = 'off';
            app.NumberofbandsDropDownLabel.Text = 'Number of bands';
            app.NumberofbandsDropDown = uidropdown(app.SummaryPlotUIFigure);
            app.NumberofbandsDropDown.Items = {'1', '2', '3'};
            app.NumberofbandsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofbandsDropDownValueChanged, true);
            app.NumberofbandsDropDown.Enable = 'off';
            app.NumberofbandsDropDown.Value = '1';
            app.FirstBandLabel = uilabel(app.SummaryPlotUIFigure);
            app.FirstBandLabel.Enable = 'off';
            app.FirstBandLabel.Text = 'First (Bottom) Band Label';
            app.FirstBand = uieditfield(app.SummaryPlotUIFigure, 'text');
            app.FirstBand.Enable = 'off';
            app.SecondBandLabel = uilabel(app.SummaryPlotUIFigure);
            app.SecondBandLabel.Enable = 'off';
            app.SecondBandLabel.Text = 'Second (Middle) Band Label';
            app.SecondBand = uieditfield(app.SummaryPlotUIFigure, 'text');
            app.SecondBand.Enable = 'off';
            app.ThirdBandLabel = uilabel(app.SummaryPlotUIFigure);
            app.ThirdBandLabel.Enable = 'off';
            app.ThirdBandLabel.Text = 'Third (Top) Band Label';
            app.ThirdBand = uieditfield(app.SummaryPlotUIFigure, 'text');
            app.ThirdBand.Enable = 'off';
            app.SelectOutputFolderButton = uibutton(app.SummaryPlotUIFigure, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.OutputPathField = uieditfield(app.SummaryPlotUIFigure, 'text');
            app.OutputPathField.Editable = 'off';
            app.GenerateSummaryPlotsButton = uibutton(app.SummaryPlotUIFigure, 'push');
            app.GenerateSummaryPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSummaryPlotsButtonPushed, true);
            app.GenerateSummaryPlotsButton.Text = 'Generate Summary Plots';
            app.FigureFileNameEditFieldLabel = uilabel(app.SummaryPlotUIFigure);
            app.FigureFileNameEditFieldLabel.Text = 'Figure File Name';
            app.FigureFileNameField = uieditfield(app.SummaryPlotUIFigure, 'text');
            app.FigureFileNameField.ValueChangedFcn = createCallbackFcn(app, @FigureFileNameFieldValueChanged, true);
            % Show the figure after all components are created
            app.SummaryPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SummaryPlotWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SummaryPlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SummaryPlotUIFigure)
        end
    end
end