classdef fitting_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingParametersUIFigure  matlab.ui.Figure
        ConstrainCheckBox_12       matlab.ui.control.CheckBox
        ConstrainCheckBox_11       matlab.ui.control.CheckBox
        ConstrainCheckBox_10       matlab.ui.control.CheckBox
        ConstrainCheckBox_9        matlab.ui.control.CheckBox
        ConstrainCheckBox_8        matlab.ui.control.CheckBox
        ConstrainCheckBox_7        matlab.ui.control.CheckBox
        ConstrainCheckBox_6        matlab.ui.control.CheckBox
        ConstrainCheckBox_5        matlab.ui.control.CheckBox
        ConstrainCheckBox_4        matlab.ui.control.CheckBox
        ConstrainCheckBox_3        matlab.ui.control.CheckBox
        ConstrainCheckBox_2        matlab.ui.control.CheckBox
        ConstrainCheckBox_1        matlab.ui.control.CheckBox
        UITable                    matlab.ui.control.Table
        CalculatedParametersLabel  matlab.ui.control.Label
        UpdateFittingButton        matlab.ui.control.Button
    end


    properties (Access = private)
        GelBoxApp % Description
        n
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            movegui(app.FittingParametersUIFigure,'center')
            app.GelBoxApp = caller;
            gel_data = guidata(caller.Window);
            app.n = str2num(caller.BoxSelectionDropDown.Value);
           
            par_fields = {'Peak Location';'Shape';'Amplitude';'Skewness'};
            band_no_fields = {'1';'';'';''};

            no_of_bands = numel(caller.gel_data.par_est(app.n).band_no);
            var_text = 'ConstrainCheckBox_';
            for i = 1 : no_of_bands*numel(par_fields)
                check_box_name = sprintf('%s%i',var_text,i);
                app.(check_box_name).Visible = 1;
            end
            mt.band_no_fields = repmat(band_no_fields,no_of_bands,1);
            m = 1;
            for i = 1:no_of_bands
                mt.band_no_fields{m} = sprintf('%i',i);
                m = m + numel(par_fields);
            end
            mt.par_fields = repmat(par_fields,no_of_bands,1);

            names = fieldnames(caller.gel_data.par_est);
            par_names = names(2:5,:);
            
            l = 1;
            for u = 1 : no_of_bands
                for i = 1 : numel(par_names)
                    mt.par_est_values(l,1) = caller.gel_data.par_est(app.n).(par_names{i})(u);
                    mt.par_cal_values(l,1) = caller.gel_data.par_fit(app.n).(par_names{i})(u);
                    check_box_name = sprintf('%s%i',var_text,l);
                    app.(check_box_name).Value = caller.gel_data.par_con(app.n).(par_names{i})(u);
                    l = l + 1;
                end
            end
            
            
            t = struct2table(mt);

            app.UITable.Data = t;

%             if ~isempty(caller.gel_data.par_fit(app.n).band_no)
%                 app.CalculatedParametersLabel.Enable = 1;
%                 app.CalculatedParametersTable.Enable = 'on';
%                 t2 = struct2table(caller.gel_data.par_fit(app.n));
%                 app.CalculatedParametersTable.Data = t2;
%             end


        end

        % Button pushed function: UpdateFittingButton
        function UpdateFittingButtonPushed(app, event)
            ConstrainCheckBoxValueChanged(app);
            UpdateFittingOptions(app.GelBoxApp);

            delete(app)
        end

        % Cell edit callback: UITable
        function StartingParametersTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            new_param = table2struct(app.UITable.Data);
            names = fieldnames(app.GelBoxApp.gel_data.par_est);
            par_names = names(2:5,:);
            no_of_bands = numel(app.GelBoxApp.gel_data.par_est(app.n).band_no);
            
            ll = 1;
            for ii = 1 : no_of_bands
                for jj = 1 : numel(par_names)  
                    app.GelBoxApp.gel_data.par_est(app.n).(par_names{jj})(ii) = new_param(ll,1).par_est_values;
                    ll = ll + 1;
                end                
            end
            app.GelBoxApp.gel_data.par_update(app.n) = 1;


        end

        % Value changed function: ConstrainCheckBox_1, 
        % ConstrainCheckBox_10, ConstrainCheckBox_11, 
        % ConstrainCheckBox_12, ConstrainCheckBox_2, 
        % ConstrainCheckBox_3, ConstrainCheckBox_4, 
        % ConstrainCheckBox_5, ConstrainCheckBox_6, 
        % ConstrainCheckBox_7, ConstrainCheckBox_8, 
        % ConstrainCheckBox_9
        function ConstrainCheckBoxValueChanged(app, event)

            var_text = 'ConstrainCheckBox_';
            names = fieldnames(app.GelBoxApp.gel_data.par_est);
            par_names = names(2:5,:);
            no_of_bands = numel(app.GelBoxApp.gel_data.par_con(app.n).band_no);

            ll = 1;
            for ii = 1 : no_of_bands
                for jj = 1 : numel(par_names)
                    check_box_name = sprintf('%s%i',var_text,ll);
                    app.GelBoxApp.gel_data.par_con(app.n).(par_names{jj})(ii) = app.(check_box_name).Value;
                    ll = ll + 1;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingParametersUIFigure = uifigure('Visible', 'off');
            app.FittingParametersUIFigure.Name = 'Fitting Parameters';
            app.UpdateFittingButton = uibutton(app.FittingParametersUIFigure, 'push');
            app.UpdateFittingButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFittingButtonPushed, true);
            app.UpdateFittingButton.Text = 'Update Fitting';
            app.CalculatedParametersLabel = uilabel(app.FittingParametersUIFigure);
            app.CalculatedParametersLabel.Enable = 'off';
            app.CalculatedParametersLabel.Text = 'Calculated Parameters';
            app.UITable = uitable(app.FittingParametersUIFigure);
            app.UITable.ColumnName = {'Band No'; 'Parameter'; 'Starting Parameter Estimate'; 'Calculated Parameter'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @StartingParametersTableCellEdit, true);
            app.ConstrainCheckBox_1 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_1.Visible = 'off';
            app.ConstrainCheckBox_1.Text = 'Constrain';
            app.ConstrainCheckBox_2 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_2.Visible = 'off';
            app.ConstrainCheckBox_2.Text = 'Constrain';
            app.ConstrainCheckBox_3 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_3.Visible = 'off';
            app.ConstrainCheckBox_3.Text = 'Constrain';
            app.ConstrainCheckBox_4 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_4.Visible = 'off';
            app.ConstrainCheckBox_4.Text = 'Constrain';
            app.ConstrainCheckBox_5 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_5.Visible = 'off';
            app.ConstrainCheckBox_5.Text = 'Constrain';
            app.ConstrainCheckBox_6 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_6.Visible = 'off';
            app.ConstrainCheckBox_6.Text = 'Constrain';
            app.ConstrainCheckBox_7 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_7.Visible = 'off';
            app.ConstrainCheckBox_7.Text = 'Constrain';
            app.ConstrainCheckBox_8 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_8.Visible = 'off';
            app.ConstrainCheckBox_8.Text = 'Constrain';
            app.ConstrainCheckBox_9 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_9.Visible = 'off';
            app.ConstrainCheckBox_9.Text = 'Constrain';
            app.ConstrainCheckBox_10 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_10.Visible = 'off';
            app.ConstrainCheckBox_10.Text = 'Constrain';
            app.ConstrainCheckBox_11 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_11.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_11.Visible = 'off';
            app.ConstrainCheckBox_11.Text = 'Constrain';
            app.ConstrainCheckBox_12 = uicheckbox(app.FittingParametersUIFigure);
            app.ConstrainCheckBox_12.ValueChangedFcn = createCallbackFcn(app, @ConstrainCheckBoxValueChanged, true);
            app.ConstrainCheckBox_12.Visible = 'off';
            app.ConstrainCheckBox_12.Text = 'Constrain';
            % Show the figure after all components are created
            app.FittingParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fitting_parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingParametersUIFigure)
        end
    end
end